classdef DBSModelGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DBSModelGUIUIFigure             matlab.ui.Figure
        LoadSaveDataPanel               matlab.ui.container.Panel
        TabGroup                        matlab.ui.container.TabGroup
        LoadDesignTab                   matlab.ui.container.Tab
        SelectafilewithtxtextensionthatwascreatedusingthisappLabel  matlab.ui.control.Label
        SelectFiletoLoadButton          matlab.ui.control.Button
        SaveDesignTab                   matlab.ui.container.Tab
        SaveDesignButton                matlab.ui.control.Button
        txtLabel                        matlab.ui.control.Label
        FilenameModifiers               matlab.ui.control.EditField
        FilenameLabel                   matlab.ui.control.Label
        SaveFiguresTab                  matlab.ui.container.Tab
        SaveFigureButton                matlab.ui.control.Button
        FigExtension                    matlab.ui.control.DropDown
        ViewFigureButton                matlab.ui.control.Button
        FilenameFigures                 matlab.ui.control.EditField
        FilenameEditField_2Label        matlab.ui.control.Label
        SelectFigureDropDown            matlab.ui.control.DropDown
        SelectFigureLabel               matlab.ui.control.Label
        Save3DDataTab                   matlab.ui.container.Tab
        Select3DFieldDropDown           matlab.ui.control.DropDown
        Select3DFieldDropDownLabel      matlab.ui.control.Label
        Save3DDataButton                matlab.ui.control.Button
        ProbeDesignPanel                matlab.ui.container.Panel
        ModifierTabGroup                matlab.ui.container.TabGroup
        LayoutTab                       matlab.ui.container.Tab
        LayoutTabGroup                  matlab.ui.container.TabGroup
        BostonScientificTab             matlab.ui.container.Tab
        BostonScientificElectrodesPanel  matlab.ui.container.Panel
        LayoutBSC                       matlab.ui.control.Image
        AbbottTab                       matlab.ui.container.Tab
        AbbottElectrodesPanel           matlab.ui.container.Panel
        LayoutAbbott                    matlab.ui.control.Image
        ProbeModifierTab                matlab.ui.container.Tab
        E1Separation                    matlab.ui.control.NumericEditField
        SeparationmmLabel               matlab.ui.control.Label
        SetProbeValuestoDefaultAbbottButton  matlab.ui.control.Button
        ProbeSwitch                     matlab.ui.control.Switch
        E4Separation                    matlab.ui.control.NumericEditField
        SeparationmmEditField_3Label    matlab.ui.control.Label
        E4Height                        matlab.ui.control.NumericEditField
        HeightmmEditField_4Label        matlab.ui.control.Label
        E3SectorAngle                   matlab.ui.control.NumericEditField
        SectorAngledegEditField_2Label  matlab.ui.control.Label
        E3Separation                    matlab.ui.control.NumericEditField
        SeparationmmEditField_2Label    matlab.ui.control.Label
        E3Height                        matlab.ui.control.NumericEditField
        HeightmmEditField_3Label        matlab.ui.control.Label
        E2SectorAngle                   matlab.ui.control.NumericEditField
        SectorAngledegEditFieldLabel    matlab.ui.control.Label
        E2Separation                    matlab.ui.control.NumericEditField
        SeparationmmEditFieldLabel      matlab.ui.control.Label
        E2Height                        matlab.ui.control.NumericEditField
        HeightmmEditField_2Label        matlab.ui.control.Label
        E1Height                        matlab.ui.control.NumericEditField
        HeightmmEditFieldLabel          matlab.ui.control.Label
        Level1L1Label                   matlab.ui.control.Label
        Level2L2Label                   matlab.ui.control.Label
        Level3L3Label                   matlab.ui.control.Label
        Level4L4Label                   matlab.ui.control.Label
        SetProbeValuestoDefaultBSCButton  matlab.ui.control.Button
        ProbeLabel                      matlab.ui.control.Label
        ProbeLength                     matlab.ui.control.NumericEditField
        LengthmmEditFieldLabel          matlab.ui.control.Label
        ProbeDiameter                   matlab.ui.control.NumericEditField
        DiametermmEditFieldLabel        matlab.ui.control.Label
        PlaneModifierTab                matlab.ui.container.Tab
        Conductivity                    matlab.ui.control.NumericEditField
        VolumeConductivitySmmLabel      matlab.ui.control.Label
        HPlaneZ                         matlab.ui.control.NumericEditField
        yvaluemmLabel_2                 matlab.ui.control.Label
        VPlaneY                         matlab.ui.control.NumericEditField
        yvaluemmLabel                   matlab.ui.control.Label
        PlaneSwitch                     matlab.ui.control.Switch
        HPlaneLength                    matlab.ui.control.NumericEditField
        WidthmmEditField_2Label_3       matlab.ui.control.Label
        HPlaneWidth                     matlab.ui.control.NumericEditField
        WidthmmEditField_2Label_2       matlab.ui.control.Label
        HPlaneUpperY                    matlab.ui.control.NumericEditField
        x_minmmLabel_5                  matlab.ui.control.Label
        HPlaneLowerY                    matlab.ui.control.NumericEditField
        x_minmmLabel_4                  matlab.ui.control.Label
        HPlaneUpperX                    matlab.ui.control.NumericEditField
        x_minmmLabel_3                  matlab.ui.control.Label
        HPlaneLowerX                    matlab.ui.control.NumericEditField
        x_minmmLabel_2                  matlab.ui.control.Label
        HorizontalPlanexyplaneLabel     matlab.ui.control.Label
        SetPlaneValuestoDefaultButton   matlab.ui.control.Button
        VPlaneUpperZ                    matlab.ui.control.NumericEditField
        UpperzmmEditFieldLabel          matlab.ui.control.Label
        VPlaneUpperX                    matlab.ui.control.NumericEditField
        UpperxmmEditFieldLabel          matlab.ui.control.Label
        VPlaneLowerZ                    matlab.ui.control.NumericEditField
        LowerzmmEditFieldLabel          matlab.ui.control.Label
        VerticalPlanexzplaneLabel       matlab.ui.control.Label
        VPlaneHeight                    matlab.ui.control.NumericEditField
        HeightmmEditField_5Label        matlab.ui.control.Label
        VPlaneWidth                     matlab.ui.control.NumericEditField
        WidthmmEditField_2Label         matlab.ui.control.Label
        VPlaneLowerX                    matlab.ui.control.NumericEditField
        x_minmmLabel                    matlab.ui.control.Label
        VoltagesModifierTab             matlab.ui.container.Tab
        IPGLabel                        matlab.ui.control.Label
        BaseElectrodeV                  matlab.ui.control.NumericEditField
        Electrode11VLabel_2             matlab.ui.control.Label
        LeaveeditfieldblankforunknownfloatingelectrodesLabel  matlab.ui.control.Label
        Level1L1Label_2                 matlab.ui.control.Label
        Level2L2Label_2                 matlab.ui.control.Label
        Level3L3Label_2                 matlab.ui.control.Label
        Level4L4Label_2                 matlab.ui.control.Label
        FirstElectrodeV                 matlab.ui.control.NumericEditField
        Electrode11VLabel               matlab.ui.control.Label
        SecondElectrodeV                matlab.ui.control.NumericEditField
        Electrode22AVLabel              matlab.ui.control.Label
        ThirdElectrodeV                 matlab.ui.control.NumericEditField
        Electrode32CVLabel              matlab.ui.control.Label
        FourthElectrodeV                matlab.ui.control.NumericEditField
        Electrode42BVLabel              matlab.ui.control.Label
        FifthElectrodeV                 matlab.ui.control.NumericEditField
        Electrode53AVLabel              matlab.ui.control.Label
        SixthElectrodeV                 matlab.ui.control.NumericEditField
        Electrode63CVLabel              matlab.ui.control.Label
        SeventhElectrodeV               matlab.ui.control.NumericEditField
        Electrode73BLabel               matlab.ui.control.Label
        EighthElectrodeV                matlab.ui.control.NumericEditField
        Electrode84VLabel               matlab.ui.control.Label
        SetVoltageValuestoDefaultButton  matlab.ui.control.Button
        SimulationDisplayControlsPanel  matlab.ui.container.Panel
        SpatialFiguresLabel             matlab.ui.control.Label
        SurfaceFiguresLabel             matlab.ui.control.Label
        ColorbarCheckBox                matlab.ui.control.CheckBox
        ZoomtoProbeCheckBox             matlab.ui.control.CheckBox
        DisplayAllFiguresButton         matlab.ui.control.Button
        DisplayVolumePotentialButton    matlab.ui.control.Button
        DisplayGradientofEFieldButton   matlab.ui.control.Button
        DisplayVolumeEFieldButton       matlab.ui.control.Button
        DisplaySurfaceEFieldButton      matlab.ui.control.Button
        DisplaySurfacePotentialButton   matlab.ui.control.Button
        RunSimulationsButton            matlab.ui.control.Button
        AssignElectrodeVoltagesButton   matlab.ui.control.Button
        GeneratePlaneButton             matlab.ui.control.Button
        GenerateProbeButton             matlab.ui.control.Button
        ClearTextWindowButton           matlab.ui.control.Button
        CmdOutput                       matlab.ui.control.TextArea
        DisplayTabGroup                 matlab.ui.container.TabGroup
        ProbeTab                        matlab.ui.container.Tab
        ProbeAxes                       matlab.ui.control.UIAxes
        PlaneTab                        matlab.ui.container.Tab
        PlaneAxes                       matlab.ui.control.UIAxes
        ElectrodeVoltagesTab            matlab.ui.container.Tab
        ElectrodeVoltagesAxes           matlab.ui.control.UIAxes
        SimulationResultsTab            matlab.ui.container.Tab
        ResultsTable                    matlab.ui.control.Table
        ConvergenceHistoryAxes          matlab.ui.control.UIAxes
        SurfacePotentialTab             matlab.ui.container.Tab
        SurfacePotentialAxes            matlab.ui.control.UIAxes
        SurfaceEFieldTab                matlab.ui.container.Tab
        SurfaceEFieldAxes               matlab.ui.control.UIAxes
        VolumePotentialTab              matlab.ui.container.Tab
        VolumePotentialAxes             matlab.ui.control.UIAxes
        VolumeEFieldTab                 matlab.ui.container.Tab
        VolumeEFieldAxes                matlab.ui.control.UIAxes
        GradofEFieldTab                 matlab.ui.container.Tab
        GradofEFieldAxes                matlab.ui.control.UIAxes
        StatusLamp                      matlab.ui.control.Lamp
        StatusLampLabel                 matlab.ui.control.Label
        InitializeParallelProcessingButton  matlab.ui.control.Button
    end

    
    properties (Access = private)
        AppTitle = "DBS Model GUI Version 1.0"; % Title of application
      
        % Function outputs needed as inputs by other functions
        bem02out 

        % Used in multiple separate functions
        electrodeVoltages = [0,0,0,0,0,0,0,0,0];
        ElectrodeIndexes
        indexv
        V
        PC
        cv
        pointsXZ
        EofXZ
        PofXZ
        countXZ
        x
        z
        pointsXY
        EofXY
        PofXY
        countXY
        y
        color

        xmin
        xmax
        ymin
        ymax

        numCores; % Number of cores to be used (dynamically found using [ feature('numcores') ]
        
        keynum = 010011100110000101001110; % For detecting NaN from *.txt file (binary for NaN)
        filecheckcode = 20241.0 % First number in saved file to detect valid filetype
        probetype % Second number in saved file to detect probe type (BSC or Abbott)
        planetype % Third number in saved file to detect plane type (Vertical or Horizontal)
        
        keycheck = 0; % Check for button press while saving figure (0 no, 1 yes)
        
        Save3DApp % Sub-window for saving 
        EField % 3D Electric field to be exported

        outputFilename1 = "probecurrents.txt" % File containing voltages, currents, and impedances
        % outputFilename2 = "EField3D.txt" % File containing 3D E-Field grid and values
    end
    
    methods (Access = private)
        
        function bem01out = bem01_DBS_probe_creator(app, bem01in, strge)
            %   This script creates triangular surface mesh for a long (bent) object
            %   with imprinted electrodes
            %
            %   Copyright SNM 2017-2024 
        
            % From structure to individual fields
            cellfun(@(f) assignin('caller', f, bem01in.(f)), fieldnames(bem01in));
        
            %%  Set paths to local engine folders
            s = pwd;
            if(~isunix) slash = '\'; else slash = '/'; end
            warning off; rmpath(genpath(s)); warning on;
            engine_path =   [s, slash, 'ProbeEngine']; 
            addpath(engine_path);
            engine_path =   [s, slash, 'SolutionEngine']; 
            addpath(engine_path);
            
            %%  Create probe's centerline
            N               = 160;                  %   Subdivisions
            par             = linspace(0, L, N);
            x               = 0*par;                %   Segments
            y               = 0*par;                %   Segments
            z               = par;                  %   Segments
            Pcenter(:, 1)   = x';
            Pcenter(:, 2)   = y';
            Pcenter(:, 3)   = z';
            
            %%  Create object's cross-section: a cylindrical cross section
            R           = a/2;
            M           = 49;           %   number of cross-section subdivisions (better be odd)
            x           = a/2*cos(2*pi*[0:M-1]/M);
            y           = a/2*sin(2*pi*[0:M-1]/M);
            
            %%  Create 2-manifold surface mesh in the form of the (bent) cylinder
            [P, t, normals] = meshsurface(Pcenter, x, y); 

            
            %%  Create cap(s)
            startend = 1;
            [Pe, te, ne] = mesh_cap_sphere(startend, Pcenter, P, t, M, a, a);
            %[Pe, te, ne] = mesh_cap_flat(startend, Pcenter, P, t, length(x));
            startend = 2;
            [Ps, ts, ns] = mesh_cap_flat(startend, Pcenter, P, t, length(x));
            %%  Combine meshes with caps
            [P, t, normals] = meshcombine(P, Pe, Ps, t, te, ts, normals, ne, ns);
            size(P)
            [P, t] = fixmesh(P, t, 1e-6);
            
            
            %%  Input data for imprinting electrodes (from GUI)
          
            strge.R    = R;    %   already defined above, mm
            
            %%  Imprinting electrodes
            %   Angles are running from 0 to pi and from -pi to 0
            strge.NumberOfElectrodes    = length(strge.type); 
            angle                       = strge.angle;      %   in degrees
            angle2                      = 120-angle;        %   angles must run from -180 to 180 (due to atan)
            strge.phi1 = [];
            strge.phi2 = [];
            k = 0;
            for m = 1:strge.NumberOfElectrodes
                if strge.type(m)==1
                    strge.phi1 = [strge.phi1, 0];
                    strge.phi2 = [strge.phi2, 0];
                else
                    k = k + 1;
                    if k == 1       
                        strge.phi1 = [strge.phi1, -0.5*angle];
                        strge.phi2 = [strge.phi2, +0.5*angle];
                    end
                    if k == 2
                        strge.phi1 = [strge.phi1, 0.5*angle+1*angle2];
                        strge.phi2 = [strge.phi2, 1.5*angle+1*angle2];
                    end
                    if k == 3
                        strge.phi1 = [strge.phi1, 1.5*angle+2*angle2-360];
                        strge.phi2 = [strge.phi2, 2.5*angle+2*angle2-360];
                        k = 0;
                    end
                end
            end
            strge.phi1 = strge.phi1/180*pi;
            strge.phi2 = strge.phi2/180*pi;
            
            [P, t, normals, IndicatorElectrodes] = meshimprint_sector(P, t, normals, strge);
        
            % From individual fields to structure
            bem01out = struct('strge', strge, 'P', P, 't', t, 'normals', normals, 'IndicatorElectrodes', IndicatorElectrodes);
        end
    

        function bem02out = bem02_mesh_combiner(app, bem01out)
            %   This is a mesh processor script: it computes basis triangle parameters
            %   and necessary potential integrals, and constructs a combined mesh of a
            %   multi-object structure (for example, a head or a whole body)
            
            %%  Define EM constants
            eps0        = 8.85418782e-012/1e3;  %   Dielectric permittivity of vacuum(~air) F/mm
            mu0         = 1.25663706e-006/1e3;  %   Magnetic permeability of vacuum(~air) H/mm
            
            %% Identify tissue filenames
            clear name;
            name{1} = 'geometry.mat';           %   Load volume first
            load(name{1});
            name{2} = 'probe.mat';              %   Load the probe next
            
            %%  Load tissue meshes and combine individual meshes into a single mesh
            PP = [];
            tt = [];
            nnormals = [];
            Indicator = [];
            IndicatorElectrodesTemp = IndicatorElectrodes;
            %   Combine individual meshes into a single mesh
            tic
            for m = 1:length(name)
                if m == 1
                    load(name{m});
                else
                    % From structure to individual fields
                    cellfun(@(f) assignin('caller', f, bem01out.(f)), fieldnames(bem01out));
                end
                t = meshreorient(P, t, normals);
                tt = [tt; t+size(PP, 1)];
                PP = [PP; P];
                nnormals                = [nnormals; normals];    
                Indicator               = [Indicator; repmat(m, size(t, 1), 1)];
                if m > 1
                    IndicatorElectrodes(IndicatorElectrodes>0) = IndicatorElectrodes(IndicatorElectrodes>0) + max(IndicatorElectrodesTemp);
                    IndicatorElectrodesTemp = [IndicatorElectrodesTemp; IndicatorElectrodes];
                end
                disp(['Successfully loaded file [' name{m} ']']);
            end
            t = tt;
            P = PP;
            normals = nnormals;
            IndicatorElectrodes = IndicatorElectrodesTemp;
            disp([newline 'Base meshes loaded in ' num2str(toc) ' s']);
            
            %% Put electrodes up front sequentially (1, 2, 3, etc.)
              %  Now put electrodes up front sequentially (1, 2, 3, etc.)
            tt = [];
            nn = [];
            ie = [];
            ii = [];
            for m = 1:max(IndicatorElectrodes) 
                index  = IndicatorElectrodes==m;
                tt     = [tt; t(index, :)];
                nn     = [nn; normals(index, :)];
                ie     = [ie; m*ones(sum(index), 1)];
                ii     = [ii; Indicator(index)];
            end
            index               = IndicatorElectrodes==0;
            t                   = [tt; t(index, :)];
            normals             = [nn; normals(index, :)];
            Indicator           = [ii; Indicator(index)];
            IndicatorElectrodes = [ie; IndicatorElectrodes(index)];
            
            %%  Assign conductivities
            M           = size(t, 1);
            condWM      = app.Conductivity.Value;     % S/mm 
            
            condin      = zeros(M, 1);
            condout     = zeros(M, 1);
            Contrast    = zeros(M, 1);
            
            %   Lead
            condout(Indicator==1)   = condWM;
            condin(Indicator==1)    = 0;
            condout(Indicator==2)   = condWM;
            condin(Indicator==2)    = 0;
            
            %  Lead, at electrodes (this is critical)
            condin(IndicatorElectrodes>0)   = 1e12;
            
            %   Contrast
            Contrast = (condin-condout)./(condin+condout);
            
            %%  Fix triangle orientation (just in case, optional)
            tic
            t = meshreorient(P, t, normals);
            
            %%   Process other mesh data
            Center      = 1/3*(P(t(:, 1), :) + P(t(:, 2), :) + P(t(:, 3), :));  %   face centers
            Area        = meshareas(P, t);  
            disp([newline 'Triangle properties computed in ' num2str(toc) ' s']);
            
            %%  Check for and process triangles that have coincident centroids
            tic
            disp('Checking combined mesh for duplicate facets ...');
            [P, t, normals, Center, Area, Indicator, condin, condout, Contrast] = ...
                clean_coincident_facets(P, t, normals, Center, Area, Indicator, condin, condout, Contrast);
            disp('Resolved all duplicate facets');
            N           = size(t, 1);
            disp([newline 'Duplicate facets resolved in ' num2str(toc) ' s']);
        
            % From individual fields to structure
            bem02out = struct('strge', strge, 'P', P, 't', t, 'normals', normals, 'Indicator', Indicator, 'Center', Center, 'Area', Area, ...
                              'condin', condin, 'condout', condout, 'Contrast', Contrast, 'IndicatorElectrodes', IndicatorElectrodes,...
                              'eps0', eps0, 'mu0', mu0);
            
        end
    
        
        function [color, countXZ, PofXZ, EofXZ, TofXZ, pointsXZ, x, z] = bem03_define_plane(app,bem02out, bem03in)
            %   This script defines three observation planes and plots cross-sections and
            %   NIfTI data when availble
            %
            %   Copyright SNM/WAW 2017-2020
        
            % From structure to individual fields
            cellfun(@(f) assignin('caller', f, bem02out.(f)), fieldnames(bem02out));
            cellfun(@(f) assignin('caller', f, bem03in.(f)), fieldnames(bem03in));
            
            %% Create AUX grid
            numPoints = 200;
            
            x = linspace(xmin, xmax, numPoints);
            z = linspace(zmin, zmax, numPoints);
            [xx, zz] = meshgrid(x, z);
            xx = reshape(xx, [], 1);
            zz = reshape(zz, [], 1);
            pointsXZ = [xx zz];
            pointsXZ(:, 3) = pointsXZ(:, 2);
            pointsXZ(:, 2) = Y;
            
            %%   Process cross-section data to enable fast (real time) display 
            %   This block finds all edges and attached triangles for separate brain
            %   compartments. This script is required for subsequent visualizations.
            %   Process surface model data
            tic
            %   Preallocate cell arrays
            m_max = 2; %length(name);
            tS = cell(m_max, 1);
            nS = tS; %  Reuse this empty cell array for other initialization
            eS = tS;
            TriPS = tS;
            TriMS = tS;
            PS = P;
            for m = 1:m_max
                tS{m} = t(Indicator == m, :);
                nS{m} = normals(Indicator == m, :);
                [eS{m}, TriPS{m}, TriMS{m}] = mt(tS{m}); 
            end
            SurfaceDataProcessTime = toc
            
            color   = prism(m_max); color(4, :) = [0 1 1];
            PofXZ = cell(m_max, 1);   %   intersection nodes for a tissue
            EofXZ = cell(m_max, 1);   %   edges formed by intersection nodes for a tissue
            TofXZ = cell(m_max, 1);   %   intersected triangles
            NofXZ = cell(m_max, 1);   %   normal vectors of intersected triangles
            countXZ = [];   %   number of every tissue present in the slice
            for m = 1:m_max 
                [Pi, ti, polymask, flag] = meshplaneintXZ(PS, tS{m}, eS{m}, TriPS{m}, TriMS{m}, Y);
                if flag % intersection found                
                    countXZ               = [countXZ m];
                    PofXZ{m}            = Pi;               %   intersection nodes
                    EofXZ{m}            = polymask;         %   edges formed by intersection nodes
                    TofXZ{m}            = ti;               %   intersected triangles
                    NofXZ{m}            = nS{m}(ti, :);     %   normal vectors of intersected triangles        
                end
            end

        end
    
        
        function [color, countXY, PofXY, EofXY, TofXY, pointsXY, x, y] = bem03_define_plane_xy(app,bem02out, bem03in)

            %   This script defines three observation planes and plots cross-sections and
            %   NIfTI data when availble
            %
            %   Copyright SNM/WAW 2017-2020
        
            % From structure to individual fields
            cellfun(@(f) assignin('caller', f, bem02out.(f)), fieldnames(bem02out));
            cellfun(@(f) assignin('caller', f, bem03in.(f)), fieldnames(bem03in));
            
            %% Create AUX grid
            numPoints = 200;
            
            x = linspace(xmin, xmax, numPoints);
            y = linspace(ymin, ymax, numPoints);
            [xx, yy] = meshgrid(x, y);
            xx = reshape(xx, [], 1);
            yy = reshape(yy, [], 1);
            pointsXY = [xx yy];
            pointsXY(:, 3) = Z;
            
            %%   Process cross-section data to enable fast (real time) display 
            %   This block finds all edges and attached triangles for separate brain
            %   compartments. This script is required for subsequent visualizations.
            %   Process surface model data
            tic
            %   Preallocate cell arrays
            m_max = 2; %length(name);
            tS = cell(m_max, 1);
            nS = tS; %  Reuse this empty cell array for other initialization
            eS = tS;
            TriPS = tS;
            TriMS = tS;
            PS = P;
            for m = 1:m_max
                tS{m} = t(Indicator == m, :);
                nS{m} = normals(Indicator == m, :);
                [eS{m}, TriPS{m}, TriMS{m}] = mt(tS{m}); 
            end
            SurfaceDataProcessTime = toc
            
            color   = prism(m_max); color(4, :) = [0 1 1];
            PofXY = cell(m_max, 1);   %   intersection nodes for a tissue
            EofXY = cell(m_max, 1);   %   edges formed by intersection nodes for a tissue
            TofXY = cell(m_max, 1);   %   intersected triangles
            NofXY = cell(m_max, 1);   %   normal vectors of intersected triangles
            countXY = [];   %   number of every tissue present in the slice
            for m = 1:m_max 
                [Pi, ti, polymask, flag] = meshplaneintXY(PS, tS{m}, eS{m}, TriPS{m}, TriMS{m}, Z);
                if flag % intersection found                
                    countXY             = [countXY m];
                    PofXY{m}            = Pi;               %   intersection nodes
                    EofXY{m}            = polymask;         %   edges formed by intersection nodes
                    TofXY{m}            = ti;               %   intersected triangles
                    NofXY{m}            = nS{m}(ti, :);     %   normal vectors of intersected triangles        
                end
            end

        end


        function [ElectrodeIndexes, indexv, V] = bem04_configure_electrodes(app,electrodeVoltages, bem02out)
            %   This script introduces electrode data
            %   Copyright SNM/WAW 2018-2022
        
            % From structure to individual fields
            cellfun(@(f) assignin('caller', f, bem02out.(f)), fieldnames(bem02out));
            
            %%  Define global electrode indexes (cell array ElectrodeIndexes)
            ElectrodeIndexes = cell(max(IndicatorElectrodes), 1);
            for j = 1:max(IndicatorElectrodes)
                ElectrodeIndexes{j} = find(IndicatorElectrodes==j);
            end
            indexv = transpose(vertcat(ElectrodeIndexes{:}));
            
            %%  Define the voltage excitation vector (floating electrodes must be assigned NaN)
            %   Voltage (V) applied to each electrode facet
            V      = [];   
            for j = 1:max(IndicatorElectrodes)
                if ~isnan(electrodeVoltages(j))
                    V = [V; electrodeVoltages(j)*ones(length(ElectrodeIndexes{j}), 1)];
                else
                    V = [V; 0.0*ones(length(ElectrodeIndexes{j}), 1)];
                end
            end

        end

        
        function bem11out = bem11_nintegral_generator(app,bem02out)
           % From structure to individual fields
            cellfun(@(f) assignin('caller', f, bem02out.(f)), fieldnames(bem02out));
        
            %%   Add accurate integration for electric field/electric potential on neighbor facets
            %   Indexes into neighbor triangles
            RnumberE        = 32;      %   number of neighbor triangles for analytical integration of electric field
            RnumberP        = 32;      %   number of neighbor triangles for analytical integration of electric potential
            ineighborE      = knnsearch(Center, Center, 'k', RnumberE);   % [1:N, 1:RnumberE]
            ineighborP      = knnsearch(Center, Center, 'k', RnumberP);   % [1:N, 1:RnumberP]
            ineighborE      = ineighborE';          %   do transpose  
            ineighborP      = ineighborP';          %   do transpose  
            
            %%   Add accurate integration for electric field/electric potential on neighbor facets
            %   Indexes into neighbor triangles
            [PC0, integralpd]    = meshneighborints_Pn(P, t, normals, Area, Center, RnumberP, ineighborP);
            EC                  = meshneighborints_En(P, t, normals, Area, Center, RnumberE, ineighborE);
            ec                  = isnan(EC); 
            indicator           = sum(sum(ec))
            EC(ec)              = 0;
            pc                  = isnan(PC0); 
            indicator           = sum(sum(pc))
            PC0(pc)              = 0;
            
            %%   Normalize sparse matrix EC by variable contrast (for speed up)
            N   = size(Center, 1);
            ii  = ineighborE;
            jj  = repmat(1:N, RnumberE, 1); 
            temp = Contrast(ineighborE);
            CO  = sparse(ii, jj, temp);
            EC  = CO.*EC;
            
            %%  Electrode preconditioner M (left)
            tic
            Ne          = sum(IndicatorElectrodes>0); 
            tempC       = Center(1:Ne, :); 
            tempA       = Area(1:Ne); 
            A           = repmat(tempA, 1, length(tempA));
            M           = (1/(4*pi))*1./dist(tempC').*A';       %   base preconditioner matrix
            for m = 1:Ne                                        %   base matrix with zero elements
                M(m, m) = 0;
            end
            for m = 1:Ne                                        %   put in neighbor integrals
                mneighbors          = ineighborE(:, m);
                mneighborsn         = mneighbors(mneighbors<=Ne);
                M(mneighborsn, m)   = M(mneighborsn, m) + integralpd(mneighbors<=Ne, m)/(4*pi);
            end
            M = inv(M);                                        %   direct inversion - replace
            disp([newline 'Preconditioner computed in ' num2str(toc) ' s']);
        
            % From individual fields to structure
            bem11out = struct('EC', EC, 'PC', PC0, 'integralpd', integralpd, 'M', M); 
            
            app.PC = PC0;

        end

        
        function bem12out = bem12_charge_engine_it_func(app,bem02out, bem11out, electrodeVoltages, ElectrodeIndexes, indexv, V,electrodeImpedances)
            %   This script computes the induced surface charge density for an
            %   inhomogeneous multi-tissue object given the primary electric field, with
            %   accurate neighbor integration
            %
            %   Copyright SNM/WAW 2017-2024
            
            % From structure to individual fields
            cellfun(@(f) assignin('caller', f, bem02out.(f)), fieldnames(bem02out));
            cellfun(@(f) assignin('caller', f, bem11out.(f)), fieldnames(bem11out));
            
            %%  Parameters of the iterative solution
            iter        = 50;                      %    Maximum possible number of inner iterations in the solution 
            ITER        = 4;                       %    Maximum possible number of outer iterations in the solution 
            relres      = 1e-7;                    %    Minimum acceptable relative residual 
            
            %%  Solution for voltage electrodes
            weight          = 2.0;                  %   Global current conservation law 
                                                    %   To improve the current
                                                    %   conservation accuracy, increase
                                                    %   weight (convergence slows down)
            b           = zeros(size(t, 1), 1);     %   Right-hand side of the matrix equation
            b(indexv)   = M*V;                      %   All electrodes held at constant voltage (active, floating, sheet)
            
            %  GMRES iterative solution     
            MATVEC      = @(cv) bemf4_surface_field_lhs_v(cv, Center, Area, Contrast, normals, M, EC, PC, ElectrodeIndexes, electrodeVoltages, weight); 
            tic;
            h   = waitbar(0.5, 'Please wait - Running GMRES');
            [cv0, its, resvec] = fgmres(MATVEC, b, relres, 'restart', iter, 'max_iters', ITER, 'x0', b);
            %[cv, flag, rres, its, resvec] = gmres(MATVEC, b, [], relres, iter, [], [], b); 
            GMREStime = toc, close(h);
            %   Total electrode currents
            En = bemf4_surface_field_electric_accurate_En(cv0, Center, Area, normals, EC); %    just outside
            electrodeCurrents = zeros(length(ElectrodeIndexes), 1);    
            electrodeImpedances = zeros(length(ElectrodeIndexes), 1);
            for j = 1:length(ElectrodeIndexes)
                index = ElectrodeIndexes{j};
                electrodeCurrents(j) = +sum(condout(index).*En(index).*Area(index));
                electrodeImpedances(j) = app.electrodeVoltages(j) / electrodeCurrents(j);
            end
            % The next two lines are commented out because a table is
            % generated and outputted containing the same information.

            % electrodeCurrents
            % electrodeImpedances

            RESVEC = [];
            for m = 1:size(resvec, 2)
                if m == size(resvec, 2)
                    RESVEC = [RESVEC; resvec(1:its(2), m)];
                else
                    RESVEC = [RESVEC; resvec(:, m)];
                end
            end
        
            bem12out = struct('cv', cv0, 'electrodeCurrents', electrodeCurrents, 'RESVEC', RESVEC,'electrodeImpedances',electrodeImpedances);

            app.cv = cv0;
        end
    
        
        function [] = disableafterProbe(app)
            
            % After modifying probe values, disable other buttons (figures
            % do not automatically update)

            app.AssignElectrodeVoltagesButton.Enable = "off";
            app.GeneratePlaneButton.Enable = "off";

            app.RunSimulationsButton.Enable = "off";

            app.DisplayVolumePotentialButton.Enable = "off";
            app.DisplayGradientofEFieldButton.Enable = "off";
            app.DisplayVolumeEFieldButton.Enable = "off";
            app.DisplaySurfaceEFieldButton.Enable = "off";
            app.DisplaySurfacePotentialButton.Enable = "off";
            app.DisplayAllFiguresButton.Enable = "off";
            app.ZoomtoProbeCheckBox.Enable = "off";
            app.ColorbarCheckBox.Enable = "off";
            app.SurfaceFiguresLabel.Enable = "off";
            app.SpatialFiguresLabel.Enable = "off";


            % Checks if probe entries make sense
            
            if app.ProbeSwitch.Value == "Boston Scientific"; temp = 0;
            else; temp = app.E1Separation.Value; end

            l = temp + app.E1Height.Value + ...
                app.E2Separation.Value + app.E2Height.Value + ...
                app.E3Separation.Value + app.E3Height.Value + ...
                app.E4Separation.Value + app.E4Height.Value;
            if l > app.ProbeLength.Value
                app.CmdOutput.Value = [app.CmdOutput.Value; "Warning: Entered probe electrode geometry exceeds total probe length."];
                app.scr()
            end

            
        end
        
        function [] = disableafterPlane(app)

            % After modifying plane values, disable other buttons (figures
            % do not automatically update)

            app.AssignElectrodeVoltagesButton.Enable = "off";

            app.RunSimulationsButton.Enable = "off";

            app.DisplayVolumePotentialButton.Enable = "off";
            app.DisplayGradientofEFieldButton.Enable = "off";
            app.DisplayVolumeEFieldButton.Enable = "off";
            app.DisplaySurfaceEFieldButton.Enable = "off";
            app.DisplaySurfacePotentialButton.Enable = "off";
            app.DisplayAllFiguresButton.Enable = "off";
            app.ZoomtoProbeCheckBox.Enable = "off";
            app.ColorbarCheckBox.Enable = "off";
            app.SurfaceFiguresLabel.Enable = "off";
            app.SpatialFiguresLabel.Enable = "off";


            % Check if plane entries make sense 

            if app.VPlaneLowerX.Value >= app.VPlaneUpperX.Value
                app.CmdOutput.Value = [app.CmdOutput.Value; "Warning: Entered lower x-value is equal to or greater than upper x-value for vertical plane."];
                app.scr()
            end

            if app.VPlaneLowerZ.Value >= app.VPlaneUpperZ.Value
                app.CmdOutput.Value = [app.CmdOutput.Value; "Warning: Entered lower z-value is equal to or greater than upper z-value for vertical plane."];
                app.scr()
            end

            if app.HPlaneLowerX.Value >= app.HPlaneUpperX.Value
                app.CmdOutput.Value = [app.CmdOutput.Value; "Warning: Entered lower x-value is equal to or greater than upper x-value for horizontal plane."];
                app.scr()
            end

            if app.HPlaneLowerY.Value >= app.HPlaneUpperY.Value
                app.CmdOutput.Value = [app.CmdOutput.Value; "Warning: Entered lower y-value is equal to or greater than upper y-value for horizontal plane."];
                app.scr()
            end

        end
        
        function [] = disableafterVoltage(app)

            % After modifying electrode voltage values, disable other 
            % buttons (figures do not automatically update)

            app.RunSimulationsButton.Enable = "off";

            app.DisplayVolumePotentialButton.Enable = "off";
            app.DisplayGradientofEFieldButton.Enable = "off";
            app.DisplayVolumeEFieldButton.Enable = "off";
            app.DisplaySurfaceEFieldButton.Enable = "off";
            app.DisplaySurfacePotentialButton.Enable = "off";
            app.DisplayAllFiguresButton.Enable = "off";
            app.ZoomtoProbeCheckBox.Enable = "off";
            app.ColorbarCheckBox.Enable = "off";
            app.SurfaceFiguresLabel.Enable = "off";
            app.SpatialFiguresLabel.Enable = "off";
            
        end
        
        function [] = scr(app)

            % Spaces output text for readability and scrolls to bottom

            app.CmdOutput.Value = [app.CmdOutput.Value; " "];
            scroll(app.CmdOutput,"bottom")
            
        end
        
        function [] = panfig(app,axes)

            % Pans figure to view probe

            xpan = xlim(axes);

            if app.VPlaneUpperX.Value < -app.ProbeDiameter.Value/2 % Checking for plane on left side

                xlim(axes,[xpan(1)-8.1 xpan(2)-8.1]) % Move probe to right side of figure

            elseif app.VPlaneLowerX.Value > app.ProbeDiameter.Value/2 % Checking for plane on right side

                xlim(axes,[xpan(1)-(app.ProbeDiameter.Value+0.6) xpan(2)]) % Move probe to left side of figure

            else % Plane goes through the probe 

                xlim(axes,[xpan(1)-4.5 xpan(2)-4.5]) % Center probe in figure
    
            end
            
        end
        
        function [] = windowfix(app,axes)

            % Re-zooms window to view entire plane (or most of plane if
            % plane is too large/long)

            % Make sure entire plane is within display window
            zoom(axes,0.0001)

            % Adjust x-limits to be centered
            x0 = xlim(axes);
            middleX = (app.VPlaneUpperX.Value + app.VPlaneLowerX.Value)/2;
            hwsX = (x0(2)-x0(1))/2;
            xlim(axes,[middleX-hwsX-(app.ProbeDiameter.Value+1.6) middleX+hwsX])
            x1 = xlim(axes);

            % Adjust z-limits to be centered (figure calls them the y-limits)
            y0 = ylim(axes);
            middleY = (app.VPlaneUpperZ.Value + app.VPlaneLowerZ.Value)/2;
            hwsY = (y0(2)-y0(1))/2;
            ylim(axes,[middleY-hwsY middleY+hwsY])
            y1 = ylim(axes);

            % Re-zoom figure to appropriate size
            areaRatio = ( (y1(2)-y1(1)) * (x1(2)-x1(1)) ) / (app.VPlaneWidth.Value * app.VPlaneHeight.Value);
            zoomRatio = 0.0561635*areaRatio + 0.58409;
            zoom(axes,zoomRatio)
            x2 = xlim(axes);

            % if app.VPlaneWidth.Value > app.VPlaneHeight.Value
            %     disX = (middleX - app.VPlaneLowerX.Value)/3;
            %     xlim(axes, [x2(1)-(disX+app.ProbeDiameter.Value+0.8) x2(2)])
            % end

            if app.VPlaneHeight.Value >= 3.8*app.VPlaneWidth.Value
                zoom(axes,0.8)
            end
            
        end
        
        function c = modifierssave(app)

            % Put all editable values from Modifers panel into matrix "c".
            % app.keynum represents empty/floating electrodes.

            if isempty(app.EighthElectrodeV.Value); app.EighthElectrodeV.Value = app.keynum; end
            if isempty(app.SeventhElectrodeV.Value); app.SeventhElectrodeV.Value = app.keynum; end
            if isempty(app.SixthElectrodeV.Value); app.SixthElectrodeV.Value = app.keynum; end
            if isempty(app.FifthElectrodeV.Value); app.FifthElectrodeV.Value = app.keynum; end
            if isempty(app.FourthElectrodeV.Value); app.FourthElectrodeV.Value = app.keynum; end
            if isempty(app.ThirdElectrodeV.Value); app.ThirdElectrodeV.Value = app.keynum; end
            if isempty(app.SecondElectrodeV.Value); app.SecondElectrodeV.Value = app.keynum; end
            if isempty(app.BaseElectrodeV.Value); app.BaseElectrodeV.Value = app.keynum; end

            if app.ProbeSwitch.Value == "Boston Scientific"; app.probetype = 1;
            else; app.probetype = 2; end

            if app.PlaneSwitch.Value == "Vertical"; app.planetype = 1;
            else; app.planetype = 2; end

            c = [app.filecheckcode;... % <-- Necessary for correct file reading
                app.probetype;...      % <-- Probe type detection
                app.planetype;...      % <-- Plane type detection
                app.ProbeDiameter.Value;... % Data saving starts here
                app.ProbeLength.Value;...
                app.E4Height.Value;...
                app.E4Separation.Value;...
                app.E3Height.Value;...
                app.E3Separation.Value;...
                app.E3SectorAngle.Value;...
                app.E2Height.Value;...
                app.E2Separation.Value;...
                app.E2SectorAngle.Value;...
                app.E1Height.Value;...
                app.E1Separation.Value;...
                app.VPlaneLowerX.Value;...
                app.VPlaneUpperX.Value;...
                app.VPlaneLowerZ.Value;...
                app.VPlaneUpperZ.Value;...
                app.VPlaneY.Value;...
                app.VPlaneWidth.Value;...
                app.VPlaneHeight.Value;...
                app.HPlaneLowerX.Value;...
                app.HPlaneUpperX.Value;...
                app.HPlaneLowerY.Value;...
                app.HPlaneUpperY.Value;...
                app.HPlaneZ.Value;...
                app.HPlaneWidth.Value;...
                app.HPlaneLength.Value;...
                app.EighthElectrodeV.Value;...
                app.SeventhElectrodeV.Value;...
                app.SixthElectrodeV.Value;...
                app.FifthElectrodeV.Value;...
                app.FourthElectrodeV.Value;...
                app.ThirdElectrodeV.Value;...
                app.SecondElectrodeV.Value;...
                app.FirstElectrodeV.Value;...
                app.Conductivity.Value;...
                app.BaseElectrodeV.Value];

            % Replacing app.keynum with [] for floating electrodes
            if app.EighthElectrodeV.Value == app.keynum; app.EighthElectrodeV.Value = []; end
            if app.SeventhElectrodeV.Value == app.keynum; app.SeventhElectrodeV.Value = []; end
            if app.SixthElectrodeV.Value == app.keynum; app.SixthElectrodeV.Value = []; end
            if app.FifthElectrodeV.Value == app.keynum; app.FifthElectrodeV.Value = []; end
            if app.FourthElectrodeV.Value == app.keynum; app.FourthElectrodeV.Value = []; end
            if app.ThirdElectrodeV.Value == app.keynum; app.ThirdElectrodeV.Value = []; end
            if app.SecondElectrodeV.Value == app.keynum; app.SecondElectrodeV.Value = []; end
            if app.BaseElectrodeV.Value == app.keynum; app.BaseElectrodeV.Value = []; end
            
        end
        
        function [] = modifiersread(app,c)

            % Distributes values from input matrix "c" to corresponding
            % Modifers tab parameters

            % c(1) = code for checking for valid file type
            % c(2) = probe type (1=BSC or 2=Abbott)
            % c(3) = plane type (1=Vertical or 2=Horizontal)
            % c(4) --> c(38) = data for Modifiers panel

            if c(1) ~= app.filecheckcode
                app.CmdOutput.Value = [app.CmdOutput.Value; "Failed to load file: incompatible format or version."];
                app.scr();
            else
                app.ProbeDiameter.Value = c(4);
                app.ProbeLength.Value = c(5);
                app.E4Height.Value = c(6);
                app.E4Separation.Value = c(7);
                app.E3Height.Value = c(8);
                app.E3Separation.Value = c(9);
                app.E3SectorAngle.Value = c(10);
                app.E2Height.Value = c(11);
                app.E2Separation.Value = c(12);
                app.E2SectorAngle.Value = c(13);
                app.E1Height.Value = c(14);
                app.E1Separation.Value = c(15);

                app.VPlaneLowerX.Value = c(16);
                app.VPlaneUpperX.Value = c(17);
                app.VPlaneLowerZ.Value = c(18);
                app.VPlaneUpperZ.Value = c(19);
                app.VPlaneY.Value = c(20);
                app.VPlaneWidth.Value = c(21);
                app.VPlaneHeight.Value = c(22);

                app.HPlaneLowerX.Value = c(23);
                app.HPlaneUpperX.Value = c(24);
                app.HPlaneLowerY.Value = c(25);
                app.HPlaneUpperY.Value = c(26);
                app.HPlaneZ.Value = c(27);
                app.HPlaneWidth.Value = c(28);
                app.HPlaneLength.Value = c(29);
    
                app.EighthElectrodeV.Value = c(30);
                app.SeventhElectrodeV.Value = c(31);
                app.SixthElectrodeV.Value = c(32);
                app.FifthElectrodeV.Value = c(33);
                app.FourthElectrodeV.Value = c(34);
                app.ThirdElectrodeV.Value = c(35);
                app.SecondElectrodeV.Value = c(36);
                app.FirstElectrodeV.Value = c(37);

                app.Conductivity.Value = c(38);

                app.BaseElectrodeV.Value = c(39);

                if app.EighthElectrodeV.Value == app.keynum; app.EighthElectrodeV.Value = []; end
                if app.SeventhElectrodeV.Value == app.keynum; app.SeventhElectrodeV.Value = []; end
                if app.SixthElectrodeV.Value == app.keynum; app.SixthElectrodeV.Value = []; end
                if app.FifthElectrodeV.Value == app.keynum; app.FifthElectrodeV.Value = []; end
                if app.FourthElectrodeV.Value == app.keynum; app.FourthElectrodeV.Value = []; end
                if app.ThirdElectrodeV.Value == app.keynum; app.ThirdElectrodeV.Value = []; end
                if app.SecondElectrodeV.Value == app.keynum; app.SecondElectrodeV.Value = []; end
                if app.BaseElectrodeV.Value == app.keynum; app.BaseElectrodeV.Value = []; end

                if c(2) == 1; app.ProbeSwitch.Value = "Boston Scientific";
                elseif c(2) == 2; app.ProbeSwitch.Value = "Abbott"; end
                ProbeSwitchValueChanged(app)

                if c(3) == 1; app.PlaneSwitch.Value = "Vertical";
                elseif c(3) == 2; app.PlaneSwitch.Value = "Horizontal"; end
                PlaneSwitchValueChanged(app)

                app.CmdOutput.Value = [app.CmdOutput.Value; "Data sucessfully loaded."];
                app.scr();
            end
            
        end
        
        function default = checkDefaultsBSC(app)

            c = 0;
            if app.E1Height.Value == 0.85; c = c+1; end
            if app.E2SectorAngle.Value == 88.15; c = c+1; end
            if app.E2Separation.Value == 0.5; c = c+1; end
            if app.E2Height.Value == 1.5; c = c+1; end
            if app.E3SectorAngle.Value == 88.15; c = c+1; end
            if app.E3Separation.Value == 0.5; c = c+1; end
            if app.E3Height.Value == 1.5; c = c+1; end
            if app.E4Separation.Value == 0.5; c = c+1; end
            if app.E4Height.Value == 1.5; c = c+1; end
            if app.ProbeLength.Value == 15; c = c+1; end
            if app.ProbeDiameter.Value == 1.3; c = c+1; end
            if c == 11
                default = 1;
            else
                default = 0;
            end
            
        end
        
        function default = checkDefaultsAbbott(app)
            
            c = 0;
            if app.E1Separation.Value == 0.5; c = c+1; end
            if app.E1Height.Value == 1.5; c = c+1; end
            if app.E2SectorAngle.Value == 105.7; c = c+1; end
            if app.E2Separation.Value == 0.5; c = c+1; end
            if app.E2Height.Value == 1.5; c = c+1; end
            if app.E3SectorAngle.Value == 105.7; c = c+1; end
            if app.E3Separation.Value == 0.5; c = c+1; end
            if app.E3Height.Value == 1.5; c = c+1; end
            if app.E4Separation.Value == 0.5; c = c+1; end
            if app.E4Height.Value == 1.5; c = c+1; end
            if app.ProbeLength.Value == 15; c = c+1; end
            if app.ProbeDiameter.Value == 1.29; c = c+1; end
            if c == 12
                default = 1;
            else
                default = 0;
            end

        end
    
        
        function color = voltagecolor(app,voltages,index)

            v = voltages(index);
            if max(abs(voltages)) == 0; r = 0; 
            else
                r = abs(v/max(abs(voltages))); % Relative scale for electrode coloring 
            end

            if v > 0
                color = [1,150/255*(1-r),190/255*(1-r)]; % Pastel pink to bright red (small positive to very positive)
            elseif v < 0
                color = [0,1-r,1]; % Cyan to blue (small negative to very negative)
            else
                color = [0,0,0]; % Black for 0V
            end
            
        end
        
        function figadjust(app,axes)

            % Not done yet. Working on updating automatic window
            % adjustments when displaying figures.

            bbxmin = min(-app.ProbeDiameter.Value/2,app.VPlaneLowerX.Value); % Bounding box lower x
            bbxmax = max(app.ProbeDiameter.Value/2,app.VPlaneUpperX.Value); % Bounding box upper x

            bbzmin = min(-app.ProbeDiameter.Value/2,app.VPlaneLowerZ.Value); % Bounding box lower z
            bbzmax = max(app.ProbeLength.Value,app.VPlaneUpperZ.Value); % Bounding box upper z

            % bbw = bbxmax - bbxmin; % Bounding box width
            % bbh = bbzmax - bbzmin; % Bounding box height
            % 
            % cx = bbxmin + bbw/2; % Center x
            % cz = bbzmin + bbh/2; % Center z

            d = app.ProbeDiameter.Value + 0.6;

            axis(axes,'auto')

            if app.ZoomtoProbeCheckBox.Value == 1
                axis(axes,[bbxmin bbxmax bbzmin bbzmax])
                xl = xlim(axes)
                yl = ylim(axes)
                xlim(axes,[x1(1)])
            end
            
        end
    end
    
    methods (Access = public)
        
        function save3D(app,data3D)

            app.CmdOutput.Value = [app.CmdOutput.Value; "Saving data - please wait..."];
            app.scr();
            pause(0.1)

            % Necessary for accessing P and t for 'inpolyhedron()'
            cellfun(@(f) assignin('caller', f, app.bem02out.(f)), fieldnames(app.bem02out));

            % Received parameters from Save3D window ("secondary app")
            x_min = data3D(1,1); x_max = data3D(1,2); sx = data3D(1,3);
            y_min = data3D(2,1); y_max = data3D(2,2); sy = data3D(2,3);
            z_min = data3D(3,1); z_max = data3D(3,2); sz = data3D(3,3);

            % Approximate "n" for linspace given step size, min, and max
            nx = round((x_max - x_min)/sx + 1);
            ny = round((y_max - y_min)/sy + 1);
            nz = round((z_max - z_min)/sz + 1);

            % Generating 3D space of inputted points
            pointsX = linspace(x_min,x_max,nx);
            pointsY = linspace(y_min,y_max,ny);
            pointsZ = linspace(z_min,z_max,nz);
            [X, Y, Z] = meshgrid(pointsX,pointsY,pointsZ);
            Points = [X(:),Y(:),Z(:)];

            % Find points inside/outside 
            in = inpolyhedron(t,P,Points);
            
            % Find Volume Potential / E-Field / Gradient of E-Field
            R = 2;
            if app.Select3DFieldDropDown.Value == "Volume Potential"

                outputData = bemf5_volume_field_potential(Points, app.cv, P, t, Center, Area, normals, R);
                outputData(in,:) = 0; % Potential in probe = 0
                outputFile = "VolumePotential3D.txt";

            else % Volume E-Field

                outputData = bemf5_volume_field_electric(Points, app.cv, P, t, Center, Area, normals, R);
                outputData(in, :) = 0; % E-Field in probe = 0
                outputFile = "EField3D.txt";

            end

            % Saving 3D data to .txt file (comma & return delimited)
            writematrix([Points,outputData],outputFile);

            app.CmdOutput.Value = [app.CmdOutput.Value; strcat("3D data saved to ",outputFile,".")];
            app.scr();
            
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
            movegui(app.DBSModelGUIUIFigure,'northwest')

            currentFolder = pwd;
            app.LayoutBSC.ImageSource = strcat(currentFolder,'/BSCprobediagram.png');
            app.LayoutAbbott.ImageSource = strcat(currentFolder,'/Abbottprobediagram.png');

            app.CmdOutput.Value = app.AppTitle; % Set outputwindow text
            app.scr()

            if ~isempty(gcp('nocreate')) % Set lamp green if parallel pool is already set up
                app.CmdOutput.Value = [app.CmdOutput.Value; "Parallel pool has already been started."];
                app.StatusLamp.Color = "green"; % Lamp -> Green
                app.scr()
            end

            % Initialize table with zeros in all table cells
            app.ResultsTable.Data = zeros(9,3);

        end

        % Button pushed function: InitializeParallelProcessingButton
        function InitializeParallelProcessingButtonPushed(app, event)
            
            % Starting parallel pool
            ppool = gcp('nocreate');
            app.numCores = feature('numcores'); % Number of cores to be used
            app.StatusLamp.Color = [1,1,0]; % Lamp -> Yellow
            pause(0.1)
            if isempty(ppool)
                % app.StatusLamp.Color = [1,1,0]; % Lamp -> Yellow
                tic
                parpool(app.numCores);
                app.CmdOutput.Value = [app.CmdOutput.Value; strcat("Started parallel pool in ",num2str(toc)," s")];
                app.StatusLamp.Color = "green"; % Lamp -> Green
            else
                app.CmdOutput.Value = [app.CmdOutput.Value; "Parallel pool has already been started."];
                app.StatusLamp.Color = "green"; % Lamp -> Green
            end

            app.scr()

        end

        % Button pushed function: ClearTextWindowButton
        function ClearTextWindowButtonPushed(app, event)
            
            app.CmdOutput.Value = app.AppTitle; % Reset output text window
            app.scr()

        end

        % Button pushed function: RunSimulationsButton
        function RunSimulationsButtonPushed(app, event)
            
            cla(app.ConvergenceHistoryAxes,'reset') % Clear figure for new values

            % Functions
            app.CmdOutput.Value = [app.CmdOutput.Value; "Preparing model..."];
            app.scr()
            pause(0.05) % Allows for text to be shown before function starts running (otherwise text will lag severely)
            bem11out = bem11_nintegral_generator(app,app.bem02out); 

            app.CmdOutput.Value = [app.CmdOutput.Value; "Running BEM-FMM..."];
            app.scr()
            pause(0.05) % Allows for text to be shown before function starts running (otherwise text will lag severely)
            bem12out = bem12_charge_engine_it_func(app,app.bem02out, bem11out, app.electrodeVoltages,app.ElectrodeIndexes,app.indexv,app.V);

            % From structure to individual fields
            cellfun(@(f) assignin('caller', f, bem11out.(f)), fieldnames(bem11out));
            cellfun(@(f) assignin('caller', f, bem12out.(f)), fieldnames(bem12out));
            
            %  Plot convergence history
            semilogy(app.ConvergenceHistoryAxes,RESVEC, '-o'); grid(app.ConvergenceHistoryAxes,'on');
            title(app.ConvergenceHistoryAxes,'Relative residual of the iterative solution');
            xlabel(app.ConvergenceHistoryAxes,'Iteration number');
            ylabel(app.ConvergenceHistoryAxes,'Relative residual');

            % Display voltages, currents, and impedances on Results Table
            app.ResultsTable.Data(:,1) = flip(app.electrodeVoltages);
            for s = 1:size(electrodeCurrents,1)
                if abs(electrodeCurrents(s)) < 1e-9
                    electrodeCurrents(s) = 0;
                end
            end
            app.ResultsTable.Data(:,2) = flip(electrodeCurrents);
            for s = 1:size(electrodeImpedances,1)
                if isnan(electrodeImpedances(s))
                    electrodeImpedances(s) = Inf;
                end
            end
            app.ResultsTable.Data(:,3) = flip(electrodeImpedances);

            % Table to neatly output results
            electrodeResults = array2table([flip(electrodeCurrents),flip(electrodeImpedances)],...
                "VariableNames",["Currents (A)" "Impedances (Ohms)"],...
                "RowNames",["8 / 4" "7 / 3B" "6 / 3C" "5 / 3A" "4 / 2B" "3 / 2C" "2 / 2A" "1 / 1" "IPG"])

            %  === Save simulation results to auto-generated output file ===
            currents = flip(electrodeCurrents);
            impedances = flip(electrodeImpedances);

            % --- Not easily readable, good for computer reading
            output = [currents,impedances];
            writematrix(output,app.outputFilename1)

            % --- Easily readable, but not good for computer reading ---
            % fID = fopen(app.outputFilename,"w");
            % fprintf(fID,"Currents (A)\n");
            % for i = 1:size(currents,1)
            %     fprintf(fID,"%f\n",currents(i));
            % end
            % fprintf(fID,"\n\nImpedances (Ohms) \n");
            % for i = 1:size(impedances,1)
            %     fprintf(fID,"%f\n",impedances(i)');
            % end
            % fclose(fID);

            % Displaying...
            app.DisplayTabGroup.SelectedTab = app.SimulationResultsTab; % Change to convergence history view
            app.CmdOutput.Value = [app.CmdOutput.Value; "Convergence history displayed."];
            app.scr()

            app.ConvergenceHistoryAxes.Visible = "on";
            app.ResultsTable.Visible = "on";

            app.Save3DDataButton.Enable = "on";

            % Enable plot display buttons
            app.DisplayVolumePotentialButton.Enable = "on";
            app.DisplayGradientofEFieldButton.Enable = "on";
            app.DisplayVolumeEFieldButton.Enable = "on";
            app.DisplaySurfaceEFieldButton.Enable = "on";
            app.DisplaySurfacePotentialButton.Enable = "on";
            app.DisplayAllFiguresButton.Enable = "on";
            if app.PlaneSwitch.Value == "Vertical"
                app.ZoomtoProbeCheckBox.Enable = "on";
            else
                app.ColorbarCheckBox.Enable = "on";
            end
            app.SurfaceFiguresLabel.Enable = "on";
            app.SpatialFiguresLabel.Enable = "on";
            

        end

        % Button pushed function: GenerateProbeButton
        function GenerateProbeButtonPushed(app, event)
            
            cla(app.ProbeAxes,'reset') % Clear figure for new values

            % Input data from probe geometry (from GUI)
            bem01in.L = app.ProbeLength.Value; % Length (mm)
            bem01in.a = app.ProbeDiameter.Value; % Diameter (mm)
            clear strge;
            
            if app.ProbeSwitch.Value == "Boston Scientific"
                strge.z1 = [-(app.ProbeDiameter.Value/2),... % Bottom coordinates of all electrodes (mm)
                    app.E1Height.Value + app.E2Separation.Value,...
                    app.E1Height.Value + app.E2Separation.Value,...
                    app.E1Height.Value + app.E2Separation.Value,...
                    app.E1Height.Value + app.E2Separation.Value + app.E2Height.Value + app.E3Separation.Value,...
                    app.E1Height.Value + app.E2Separation.Value + app.E2Height.Value + app.E3Separation.Value,...
                    app.E1Height.Value + app.E2Separation.Value + app.E2Height.Value + app.E3Separation.Value,...
                    app.E1Height.Value + app.E2Separation.Value + app.E2Height.Value + app.E3Separation.Value + app.E3Height.Value + app.E4Separation.Value];
    
                strge.z2 = strge.z1 + [(app.ProbeDiameter.Value/2) + app.E1Height.Value,... % Top coordinates of all electrodes (mm)
                    app.E2Height.Value,...
                    app.E2Height.Value,...
                    app.E2Height.Value,...
                    app.E3Height.Value,...
                    app.E3Height.Value,...
                    app.E3Height.Value,...
                    app.E4Height.Value];
    
                strge.angle = app.E2SectorAngle.Value; % Sectorial angle between electrodes (for sectorial electrodes)
                strge.type = [1 2 2 2 2 2 2 1]; % Electrode type: 1 = Ring , 2 = Sectorial
            else
                strge.z1 = [app.E1Separation.Value,... % Bottom coordinates of all electrodes (mm)
                    app.E1Separation.Value + app.E1Height.Value + app.E2Separation.Value,...
                    app.E1Separation.Value + app.E1Height.Value + app.E2Separation.Value,...
                    app.E1Separation.Value + app.E1Height.Value + app.E2Separation.Value,...
                    app.E1Separation.Value + app.E1Height.Value + app.E2Separation.Value + app.E2Height.Value + app.E3Separation.Value,...
                    app.E1Separation.Value + app.E1Height.Value + app.E2Separation.Value + app.E2Height.Value + app.E3Separation.Value,...
                    app.E1Separation.Value + app.E1Height.Value + app.E2Separation.Value + app.E2Height.Value + app.E3Separation.Value,...
                    app.E1Separation.Value + app.E1Height.Value + app.E2Separation.Value + app.E2Height.Value + app.E3Separation.Value + app.E3Height.Value + app.E4Separation.Value];
    
                strge.z2 = strge.z1 + [app.E1Height.Value,... % Top coordinates of all electrodes (mm)
                    app.E2Height.Value,...
                    app.E2Height.Value,...
                    app.E2Height.Value,...
                    app.E3Height.Value,...
                    app.E3Height.Value,...
                    app.E3Height.Value,...
                    app.E4Height.Value];
    
                strge.angle = app.E2SectorAngle.Value; % Sectorial angle between electrodes (for sectorial electrodes)
                strge.type = [1 2 2 2 2 2 2 1]; % Electrode type: 1 = Ring , 2 = Sectorial
            end

            % Function
            bem01out = bem01_DBS_probe_creator(app,bem01in,strge);

            % From structure to individual fields
            cellfun(@(f) assignin('caller', f, bem01out.(f)), fieldnames(bem01out));

            %  Display constructed CAD probe model (into GUI)
            p = patch(app.ProbeAxes,'vertices', P, 'faces', t);
            p.FaceColor = 'r'; 
            p.EdgeColor = 'none';
            for m = 1:strge.NumberOfElectrodes
                p = patch(app.ProbeAxes,'vertices', P, 'faces', t(IndicatorElectrodes==m, :));
                p.FaceColor = 'c'; 
                p.EdgeColor = 'b';
            end
            axis(app.ProbeAxes,'equal'); axis(app.ProbeAxes,"tight")
            set(app.ProbeAxes,'Color','White');
            view(app.ProbeAxes,-150, 2); axis(app.ProbeAxes,'off');
            camlight(app.ProbeAxes);
            brighten(app.ProbeAxes,1);

            % Function
            app.bem02out = bem02_mesh_combiner(app,bem01out);
            
            % From structure to individual fields
            cellfun(@(f) assignin('caller', f, app.bem02out.(f)), fieldnames(app.bem02out));

            app.DisplayTabGroup.SelectedTab = app.ProbeTab; % Change to probe view
            app.ModifierTabGroup.SelectedTab = app.ProbeModifierTab;
            app.CmdOutput.Value = [app.CmdOutput.Value; "Probe geometry updated."];
            app.scr()

            app.ProbeAxes.Visible = "on";
            %app.disableafterProbe();

            app.GeneratePlaneButton.Enable = "on";


        end

        % Button pushed function: GeneratePlaneButton
        function GeneratePlaneButtonPushed(app, event)
            
            cla(app.PlaneAxes,'reset') % Clear figure for new values
            
            
            if app.PlaneSwitch.Value == "Vertical"
                % Input data for plane geometry
                Y = app.VPlaneY.Value;
                xmin0 = app.VPlaneLowerX.Value;
                xmax0 = app.VPlaneUpperX.Value;
                zmin = app.VPlaneLowerZ.Value;
                zmax = app.VPlaneUpperZ.Value;
                
                % From individual fields to structure
                bem03in = struct('Y', Y, 'xmin', xmin0, 'xmax', xmax0, 'zmin', zmin, 'zmax', zmax);

                % Function
                [app.color, app.countXZ, app.PofXZ, app.EofXZ, TofXZ, app.pointsXZ, app.x, app.z] = bem03_define_plane(app,app.bem02out, bem03in);

                % Plot the planes
                pln = patch(app.PlaneAxes,[xmin0 xmin0 xmax0 xmax0],[Y Y Y Y],  [zmin zmax zmax zmin], 'c', 'FaceAlpha', 0.35);
            else
                % Input data for plane geometry
                Z = app.HPlaneZ.Value;
                xmin0 = app.HPlaneLowerX.Value;
                xmax0 = app.HPlaneUpperX.Value;
                ymin0 = app.HPlaneLowerY.Value;
                ymax0 = app.HPlaneUpperY.Value;

                % From individual fields to structure
                bem03in = struct('Z', Z, 'xmin', xmin0, 'xmax', xmax0, 'ymin', ymin0, 'ymax', ymax0);

                % Function
                [app.color, app.countXY, app.PofXY, app.EofXY, TofXY, app.pointsXY, app.x, app.y] = bem03_define_plane_xy(app,app.bem02out, bem03in);
                
                % Plot the planes
                pln = patch(app.PlaneAxes,[xmin0 xmin0 xmax0 xmax0],[ymin0 ymax0 ymax0 ymin0], [Z Z Z Z], 'c', 'FaceAlpha', 0.35);
                app.xmin=xmin0;
                app.xmax=xmax0;
                app.ymin=ymin0;
                app.ymax=ymax0;
            end

            %  Lead body graphics
            str.EdgeColor = 'none'; str.FaceColor = [1 0.75 0.65]; str.FaceAlpha = 0.25; 
            bemf2_graphics_base(app.PlaneAxes,app.bem02out.P, app.bem02out.t(app.bem02out.IndicatorElectrodes==0, :), str);    

            %  Electrode graphics
            NoElectrodes = max(app.bem02out.IndicatorElectrodes);
            Color = jet(NoElectrodes);
            for m = 1:NoElectrodes
                str.EdgeColor = 'none'; str.FaceColor = Color(m, :); str.FaceAlpha = 1.0; 
                bemf2_graphics_base(app.PlaneAxes,app.bem02out.P, app.bem02out.t(app.bem02out.IndicatorElectrodes==m, :), str);
            end    

            %  General settings
            % direction = [0,0,1];
            % rotate(pln,direction,180) % Rotates plane intuitively, but changes object instead of axes
            
            axis(app.PlaneAxes,'equal'); axis(app.PlaneAxes,'tight'); 
            view(app.PlaneAxes,-15, 15);
            daspect(app.PlaneAxes,[1 1 1]);
            set(app.PlaneAxes,'Color','White');
            camlight(app.PlaneAxes); lighting(app.PlaneAxes,'phong');

            app.DisplayTabGroup.SelectedTab = app.PlaneTab; % Change to plane view
            app.ModifierTabGroup.SelectedTab = app.PlaneModifierTab;
            app.CmdOutput.Value = [app.CmdOutput.Value; "Plane geometry updated."];
            app.scr()

            app.PlaneAxes.Visible = "on";
            %app.disableafterPlane();
            
            app.AssignElectrodeVoltagesButton.Enable = "on";


        end

        % Button pushed function: AssignElectrodeVoltagesButton
        function AssignElectrodeVoltagesButtonPushed(app, event)
            
            cla(app.ElectrodeVoltagesAxes,'reset') % Clear figure for new values

            % Detecting NaN
            if isempty(app.BaseElectrodeV.Value); v0 = NaN; else; v0 = app.BaseElectrodeV.Value; end
            v1 = app.FirstElectrodeV.Value;
            if isempty(app.SecondElectrodeV.Value); v2 = NaN; else; v2 = app.SecondElectrodeV.Value; end
            if isempty(app.ThirdElectrodeV.Value); v3 = NaN; else; v3 = app.ThirdElectrodeV.Value; end
            if isempty(app.FourthElectrodeV.Value); v4 = NaN; else; v4 = app.FourthElectrodeV.Value; end
            if isempty(app.FifthElectrodeV.Value); v5 = NaN; else; v5 = app.FifthElectrodeV.Value; end
            if isempty(app.SixthElectrodeV.Value); v6 = NaN; else; v6 = app.SixthElectrodeV.Value; end
            if isempty(app.SeventhElectrodeV.Value); v7 = NaN; else; v7 = app.SeventhElectrodeV.Value; end
            if isempty(app.EighthElectrodeV.Value); v8 = NaN; else; v8 = app.EighthElectrodeV.Value; end


            % Input data for electrode voltages
            electrodeVoltages0 = [v0,v1,v2,v3,v4,v5,v6,v7,v8];
            app.electrodeVoltages = electrodeVoltages0;

            % Function
            [app.ElectrodeIndexes, app.indexv, app.V] = bem04_configure_electrodes(app, electrodeVoltages0, app.bem02out);

            %  Electrode graphics
            p = patch(app.ElectrodeVoltagesAxes,'vertices', app.bem02out.P, 'faces', app.bem02out.t, 'EdgeColor', 'none', 'FaceLighting', 'flat'); 
            p.FaceColor = [0.5 0.5 0.5];
            p.FaceAlpha = 0.25;  
            for j = 1:max(app.bem02out.IndicatorElectrodes)
                if ~isnan(electrodeVoltages0(j))
                    p = patch(app.ElectrodeVoltagesAxes,'vertices', app.bem02out.P, 'faces', app.bem02out.t(app.bem02out.IndicatorElectrodes==j, :), 'EdgeColor', 'none', 'FaceLighting', 'flat'); 
                    p.FaceColor = app.voltagecolor(electrodeVoltages0,j);
                else
                    p = patch(app.ElectrodeVoltagesAxes,'vertices', app.bem02out.P, 'faces', app.bem02out.t(app.bem02out.IndicatorElectrodes==j, :), 'EdgeColor', 'none', 'FaceLighting', 'flat'); 
                    p.FaceColor = 'y'; % Floating electrodes are yellow
                end
            end
            %  General settings
            daspect(app.ElectrodeVoltagesAxes,[1 1 1]); axis(app.ElectrodeVoltagesAxes,'on');
            xlabel(app.ElectrodeVoltagesAxes,'x'), ylabel(app.ElectrodeVoltagesAxes,'y'), zlabel(app.ElectrodeVoltagesAxes,'z')
            set(app.ElectrodeVoltagesAxes,'Color','White');
            camlight(app.ElectrodeVoltagesAxes); lighting(app.ElectrodeVoltagesAxes,'phong');
            view(app.ElectrodeVoltagesAxes,-200, 20);
            title(app.ElectrodeVoltagesAxes,'Blue - active electrodes; red - floating electrodes')
            camzoom(app.ElectrodeVoltagesAxes,3.5);

            app.DisplayTabGroup.SelectedTab = app.ElectrodeVoltagesTab; % Change to electrode voltages view
            app.ModifierTabGroup.SelectedTab = app.VoltagesModifierTab;
            app.CmdOutput.Value = [app.CmdOutput.Value; "Electrode voltages updated."];
            app.scr()

            app.ElectrodeVoltagesAxes.Visible = "on";

            %app.disableafterVoltage();

            app.RunSimulationsButton.Enable = "on";


        end

        % Button pushed function: DisplaySurfacePotentialButton
        function DisplaySurfacePotentialButtonPushed(app, event)
            
            cla(app.SurfacePotentialAxes,'reset') % Clear figure for new values

            % Getting necessary variables
            cellfun(@(f) assignin('caller', f, app.bem02out.(f)), fieldnames(app.bem02out));

            prec = 1e-2;
            Pot  = bemf4_surface_field_potential_accurate(app.cv, Center, Area, app.PC, prec);
            %  Find electrode voltages and standard deviations over their surfaces
            NV          = max(IndicatorElectrodes);
            MeanStdV    = zeros(NV, 2); 
            for m = 1:NV
                PotE                = Pot(IndicatorElectrodes==m);
                MeanStdV(m, 1)      = mean(PotE);
                MeanStdV(m, 2)      = std(PotE);
            end
            disp(MeanStdV);
            %   Find the P-field just inside or just outside any model surface
            objectnumber= 2;
            Pot         = Pot(Indicator==objectnumber, :);
            temp        = Pot;
            %   Shell graphics          
            title(app.SurfacePotentialAxes,'Surface potential, V')
            bemf2_graphics_surf_field_interp(app.SurfacePotentialAxes,P, t, temp, Indicator, objectnumber);
            axis(app.SurfacePotentialAxes,'on'); colormap(app.SurfacePotentialAxes,'jet');
            view(app.SurfacePotentialAxes,-180, 4);

            app.SurfacePotentialAxes.Visible = "on";

            app.DisplayTabGroup.SelectedTab = app.SurfacePotentialTab; % Change to surface potential view
            app.CmdOutput.Value = [app.CmdOutput.Value; "Surface potential displayed."];
            app.scr()
            
        end

        % Button pushed function: DisplaySurfaceEFieldButton
        function DisplaySurfaceEFieldButtonPushed(app, event)
            
            cla(app.SurfaceEFieldAxes,'reset') % Clear figure for new values

            % Getting necessary variables
            cellfun(@(f) assignin('caller', f, app.bem02out.(f)), fieldnames(app.bem02out));

            h = waitbar(0.5, 'Please wait - computing all surface fields'); 

            %   Topological low-pass solution filtering (repeat if necessary)
            C = app.cv;
            [~, Eadd] = bemf4_surface_field_electric_subdiv(C, P, t, Area, 'barycentric', 5);
            par = +1;           %      par=-1 -> E-field just inside surface; par=+1 -> E-field just outside surface     
            Eo = Eadd + par/(2)*normals.*repmat(C, 1, 3);    %   full field
            par = -1;           %      par=-1 -> E-field just inside surface; par=+1 -> E-field just outside surface     
            Ei = Eadd + par/(2)*normals.*repmat(C, 1, 3);    %   full field
            FieldsTime = toc
            close(h);

            %   Output the E-field just inside (Ei) or just outside (Eo) any model surface (tested)
            E = Eo;
            objectnumber= 2;
            E           = E(Indicator==objectnumber, :);
            temp        = sqrt(dot(E, E, 2));
            amax         = max(abs(temp));
            scale        = 0.25;
            temp(temp>+scale*amax) = +scale*amax;

            %   Shell graphics
            cla(app.SurfaceEFieldAxes);      

            %   Surface field
            bemf2_graphics_surf_field_interp(app.SurfaceEFieldAxes,P, t, temp, Indicator, objectnumber);
            if par == +1; string = ' just outside: '; end
            if par == -1; string = ' just inside: '; end
            axis(app.SurfaceEFieldAxes,'on'); colormap(app.SurfaceEFieldAxes,'jet');
            xlabel(app.SurfaceEFieldAxes,'x, mm'), ylabel(app.SurfaceEFieldAxes,'y, mm'), zlabel(app.SurfaceEFieldAxes,'z, mm');
            view(app.SurfaceEFieldAxes,-180, 4);
            title(app.SurfaceEFieldAxes,'Surface E-field magnitude, V/mm')

            app.SurfaceEFieldAxes.Visible = "on";

            app.DisplayTabGroup.SelectedTab = app.SurfaceEFieldTab; % Change to surface potential view
            app.CmdOutput.Value = [app.CmdOutput.Value; "Surface E-field displayed."];
            app.scr()
            
        end

        % Button pushed function: DisplayVolumeEFieldButton
        function DisplayVolumeEFieldButtonPushed(app, event)
            
            cla(app.VolumeEFieldAxes,'reset') % Clear figure for new values

            % Getting necessary variables
            cellfun(@(f) assignin('caller', f, app.bem02out.(f)), fieldnames(app.bem02out));

            warning off
            % Find the E-field on the AUX surface
            tic
            prec           = 1e-2;
            R              = 1;
            if app.PlaneSwitch.Value == "Vertical"
                Etotal         = bemf5_volume_field_electric(app.pointsXZ, app.cv, P, t, Center, Area, normals, R);
            else
                Etotal         = bemf5_volume_field_electric(app.pointsXY, app.cv, P, t, Center, Area, normals, R);
            end
            
            temp      = abs(sqrt(dot(Etotal, Etotal, 2)));
            th1 = 0.1*max(temp);          %   in V/mm
            th2 = min(temp);            %   in V/mm
            levels      = 7;
            if app.PlaneSwitch.Value == "Vertical"
                bemf2_graphics_vol_field(app.VolumeEFieldAxes,temp, th1, th2, levels, app.x, app.z);
                for m = app.countXZ
                    edges           = app.EofXZ{m};              %   this is for the contour
                    points          = [];
                    points(:, 1)    = +app.PofXZ{m}(:, 1);       %   this is for the contour  
                    points(:, 2)    = +app.PofXZ{m}(:, 3);       %   this is for the contour
                    patch(app.VolumeEFieldAxes,'Faces', edges, 'Vertices', points, 'EdgeColor', app.color(m, :), 'LineWidth', 2.0);    %   this is contour plot
                end
                xlabel(app.VolumeEFieldAxes,'x, mm'); ylabel(app.VolumeEFieldAxes,'z, mm');
                axis(app.VolumeEFieldAxes,'equal');  axis(app.VolumeEFieldAxes,'tight'); 
                axis(app.VolumeEFieldAxes,[-1, 10, -10, 15]);
            else
                bemf2_graphics_vol_field_notext(app.VolumeEFieldAxes,temp, th1, th2, levels, app.x, app.y,app.ColorbarCheckBox.Value);
                % for m = app.countXZ
                %     edges           = app.EofXY{m};              %   this is for the contour
                %     points          = [];
                %     points(:, 1)    = +app.PofXY{m}(:, 1);       %   this is for the contour  
                %     points(:, 2)    = +app.PofXY{m}(:, 3);       %   this is for the contour
                %     patch(app.VolumeEFieldAxes,'Faces', edges, 'Vertices', points, 'EdgeColor', app.color(m, :), 'LineWidth', 2.0);    %   this is contour plot
                % end
                xlabel(app.VolumeEFieldAxes,'x, mm'); ylabel(app.VolumeEFieldAxes,'y, mm');
                axis(app.VolumeEFieldAxes,'equal');  axis(app.VolumeEFieldAxes,'tight'); 
                axis(app.VolumeEFieldAxes,[app.xmin, app.xmax, app.ymin, app.ymax]);
            end
            
            set(app.VolumeEFieldAxes,'Color','White');

            %   Electrodes
            IndicatorElectrodesPart = IndicatorElectrodes; IndicatorElectrodesPart(IndicatorElectrodesPart==1) =0;
            if app.PlaneSwitch.Value == "Vertical"
                bemf1_graphics_electrodes(app.VolumeEFieldAxes,P, t, IndicatorElectrodesPart, app.electrodeVoltages, 2);
                camzoom(app.VolumeEFieldAxes,3); colorbar(app.VolumeEFieldAxes,'off');
            else
                bemf1_graphics_electrodes(app.VolumeEFieldAxes,P, t, IndicatorElectrodesPart, app.electrodeVoltages, 1);
                camzoom(app.VolumeEFieldAxes,1);
            end
             

            if app.PlaneSwitch.Value == "Vertical"
                if app.ZoomtoProbeCheckBox.Value == 1
                    app.panfig(app.VolumeEFieldAxes)
                else
                    app.windowfix(app.VolumeEFieldAxes)
                end
            end

            axis(app.VolumeEFieldAxes,'off');

            app.VolumeEFieldAxes.Visible = "on";

            app.DisplayTabGroup.SelectedTab = app.VolumeEFieldTab; % Change to plane view
            app.CmdOutput.Value = [app.CmdOutput.Value; "Volume E-field displayed."];
            app.scr()
            
        end

        % Button pushed function: DisplayGradientofEFieldButton
        function DisplayGradientofEFieldButtonPushed(app, event)
            
            cla(app.GradofEFieldAxes,'reset') % Clear figure for new values

            % Getting necessary variables
            cellfun(@(f) assignin('caller', f, app.bem02out.(f)), fieldnames(app.bem02out));

            warning off

            %  Find the E-field tensor gradient on the AUX surface
            prec           = 1e-2;
            R              = 0.0; %   It has a different meaning here
            if app.PlaneSwitch.Value == "Vertical"
                [Exsec, Eysec, Ezsec] = bemf5_volume_field_electric_grad(app.pointsXZ, app.cv, P, t, Center, Area, normals, R);
            else
                [Exsec, Eysec, Ezsec] = bemf5_volume_field_electric_grad(app.pointsXY, app.cv, P, t, Center, Area, normals, R);
            end
            fieldPlaneTime = toc  
            Ex          = Exsec;
            Ey          = Eysec;
            Ez          = Exsec;

            %   Compute the tensor norm
            if app.PlaneSwitch.Value == "Vertical"
                Ms = sqrt(size(app.pointsXZ, 1));
            else
                Ms = sqrt(size(app.pointsXY, 1));
            end
            
            temp = zeros(Ms*Ms, 1);
            for m = 1:Ms*Ms
                temp(m) = norm([Ex(m, :); Ey(m, :); Ez(m, :)]);
                %temp(m) = norm([Ex(m, :); Ey(m, :); Ez(m, :)]*[0 0 1]');
            end

            %  Display fields
            th1 = 0.55;          %   in V/mm^2
            th2 = min(temp);            %   in V/mm^2
            levels      = 7;
            if app.PlaneSwitch.Value == "Vertical"
                bemf2_graphics_vol_field(app.GradofEFieldAxes, temp, th1, th2, levels, app.x, app.z);
            else
                bemf2_graphics_vol_field_notext(app.GradofEFieldAxes, temp, th1, th2, levels, app.x, app.y,app.ColorbarCheckBox.Value);
            end

            % E-field plot: tissue boundaries
            if app.PlaneSwitch.Value == "Vertical"

                for m = app.countXZ
                    edges           = app.EofXZ{m};              %   this is for the contour
                    points          = [];
                    points(:, 1)    = +app.PofXZ{m}(:, 1);       %   this is for the contour  
                    points(:, 2)    = +app.PofXZ{m}(:, 3);       %   this is for the contour
                    patch(app.GradofEFieldAxes,'Faces', edges, 'Vertices', points, 'EdgeColor', app.color(m, :), 'LineWidth', 2.0);    %   this is contour plot
                end
                xlabel(app.GradofEFieldAxes,'x, m'); ylabel(app.GradofEFieldAxes,'z, m');
                axis(app.GradofEFieldAxes,'equal');  axis(app.GradofEFieldAxes,'tight'); 
                axis(app.GradofEFieldAxes,[-1, 10, -10, 15]);
                set(app.GradofEFieldAxes,'Color','White');

            else

                % for m = app.countXY
                %     edges           = app.EofXY{m};              %   this is for the contour
                %     points          = [];
                %     points(:, 1)    = +app.PofXY{m}(:, 1);       %   this is for the contour  
                %     points(:, 2)    = +app.PofXY{m}(:, 3);       %   this is for the contour
                %     patch(app.GradofEFieldAxes,'Faces', edges, 'Vertices', points, 'EdgeColor', app.color(m, :), 'LineWidth', 2.0);    %   this is contour plot
                % end
                xlabel(app.GradofEFieldAxes,'x, mm'); ylabel(app.GradofEFieldAxes,'y, mm');
                axis(app.GradofEFieldAxes,'equal');  axis(app.GradofEFieldAxes,'tight'); 
                axis(app.GradofEFieldAxes,[app.xmin, app.xmax, app.ymin, app.ymax]);
                set(app.GradofEFieldAxes,'Color','White');

            end

            %   Electrodes
            IndicatorElectrodesPart = IndicatorElectrodes; IndicatorElectrodesPart(IndicatorElectrodesPart==1) =0;
            if app.PlaneSwitch.Value == "Vertical"
                bemf1_graphics_electrodes(app.GradofEFieldAxes,P, t, IndicatorElectrodesPart, app.electrodeVoltages, 2);
                camzoom(app.GradofEFieldAxes,3); colorbar(app.GradofEFieldAxes,'off');
            else
                bemf1_graphics_electrodes(app.GradofEFieldAxes,P, t, IndicatorElectrodesPart, app.electrodeVoltages, 1);
                camzoom(app.GradofEFieldAxes,1);
            end
             

            if app.PlaneSwitch.Value == "Vertical"
                if app.ZoomtoProbeCheckBox.Value == 1
                    app.panfig(app.GradofEFieldAxes)
                else
                    app.windowfix(app.GradofEFieldAxes)
                end
            end

            axis(app.GradofEFieldAxes,'off');

            app.GradofEFieldAxes.Visible = "on";

            app.DisplayTabGroup.SelectedTab = app.GradofEFieldTab; % Change to plane view
            app.CmdOutput.Value = [app.CmdOutput.Value; "Gradient of E-field displayed."];
            app.scr()
            
        end

        % Button pushed function: DisplayVolumePotentialButton
        function DisplayVolumePotentialButtonPushed(app, event)
            
            cla(app.VolumePotentialAxes,'reset') % Clear figure for new values

            % Getting necessary variables
            cellfun(@(f) assignin('caller', f, app.bem02out.(f)), fieldnames(app.bem02out));

            warning off
            prec           = 1e-2;
            R              = 1;
            if app.PlaneSwitch.Value == "Vertical"
                Vtotal         = bemf5_volume_field_potential(app.pointsXZ, app.cv, P, t, Center, Area, normals, R);
            else
                Vtotal         = bemf5_volume_field_potential(app.pointsXY, app.cv, P, t, Center, Area, normals, R);
            end
            temp      = Vtotal;
            th1 = max(temp);            %   in V
            th2 = min(temp);            %   in V
            levels      = 15;
            if app.PlaneSwitch.Value == "Vertical"
                bemf2_graphics_vol_field(app.VolumePotentialAxes,temp, th1, th2, levels, app.x, app.z);
            else
                bemf2_graphics_vol_field_notext(app.VolumePotentialAxes,temp, th1, th2, levels, app.x, app.y,app.ColorbarCheckBox.Value);
            end
            
            % E-field plot: tissue boundaries
            if app.PlaneSwitch.Value == "Vertical"
                for m = app.countXZ
                    edges           = app.EofXZ{m};              %   this is for the contour
                    points          = [];
                    points(:, 1)    = +app.PofXZ{m}(:, 1);       %   this is for the contour  
                    points(:, 2)    = +app.PofXZ{m}(:, 3);       %   this is for the contour
                    patch(app.VolumePotentialAxes,'Faces', edges, 'Vertices', points, 'EdgeColor', app.color(m, :), 'LineWidth', 2.0);    %   this is contour plot
                end
                xlabel(app.VolumePotentialAxes,'x, mm'); ylabel(app.VolumePotentialAxes,'z, mm');
                axis(app.VolumePotentialAxes,'equal');  axis(app.VolumePotentialAxes,'tight'); 
                axis(app.VolumePotentialAxes,[-1, 10, -10, 15]);
                set(app.VolumePotentialAxes,'Color','White');
            else
                % for m = app.countXY
                %     edges           = app.EofXY{m};              %   this is for the contour
                %     points          = [];
                %     points(:, 1)    = +app.PofXY{m}(:, 1);       %   this is for the contour  
                %     points(:, 2)    = +app.PofXY{m}(:, 3);       %   this is for the contour
                %     patch(app.VolumePotentialAxes,'Faces', edges, 'Vertices', points, 'EdgeColor', app.color(m, :), 'LineWidth', 2.0);    %   this is contour plot
                % end
                xlabel(app.VolumePotentialAxes,'x, mm'); ylabel(app.VolumePotentialAxes,'y, mm');
                axis(app.VolumePotentialAxes,'equal');  axis(app.VolumePotentialAxes,'tight'); 
                axis(app.VolumePotentialAxes,[app.xmin, app.xmax, app.ymin, app.ymax]);
                set(app.VolumePotentialAxes,'Color','White');
            end
            

            %   Electrodes
            IndicatorElectrodesPart = IndicatorElectrodes; IndicatorElectrodesPart(IndicatorElectrodesPart==1) =0;
            if app.PlaneSwitch.Value == "Vertical"
                bemf1_graphics_electrodes(app.VolumePotentialAxes,P, t, IndicatorElectrodesPart, app.electrodeVoltages, 2);
                camzoom(app.VolumePotentialAxes,3); colorbar(app.VolumePotentialAxes,'off');
            else
                bemf1_graphics_electrodes(app.VolumePotentialAxes,P, t, IndicatorElectrodesPart, app.electrodeVoltages, 1);
                camzoom(app.VolumePotentialAxes,1); 
            end
            

            if app.PlaneSwitch.Value == "Vertical"
                if app.ZoomtoProbeCheckBox.Value == 1
                    app.panfig(app.VolumePotentialAxes)
                else
                    app.windowfix(app.VolumePotentialAxes)
                end
            end

            axis(app.VolumePotentialAxes,'off');

            app.VolumePotentialAxes.Visible = "on";

            app.DisplayTabGroup.SelectedTab = app.VolumePotentialTab; % Change to plane view
            app.CmdOutput.Value = [app.CmdOutput.Value; "Volume Potential displayed."];
            app.scr()

        end

        % Button pushed function: SetVoltageValuestoDefaultButton
        function SetVoltageValuestoDefaultButtonPushed(app, event)
            
            % Default values: -1V for electrode 1, floating for the rest.
            % "[]" (empty) corresponds to floating electrodes.

            app.BaseElectrodeV.Value = 0; % IPG = GND
            app.FirstElectrodeV.Value = -1;
            app.SecondElectrodeV.Value = [];
            app.ThirdElectrodeV.Value = [];
            app.FourthElectrodeV.Value = [];
            app.FifthElectrodeV.Value = [];
            app.SixthElectrodeV.Value = [];
            app.SeventhElectrodeV.Value = [];
            app.EighthElectrodeV.Value = [];

            app.disableafterVoltage();
            

        end

        % Value changed function: E2SectorAngle
        function E2SectorAngleValueChanged(app, event)
           
            % Automatically update other sector angle value.
            % Sector angles for segmented electrodes must be the same.

            value = app.E2SectorAngle.Value;
            app.E3SectorAngle.Value = value;
            app.disableafterProbe();
            
        end

        % Value changed function: E3SectorAngle
        function E3SectorAngleValueChanged(app, event)
            
            % Automatically update other sector angle value.
            % Sector angles for segmented electrodes must be the same.

            value = app.E3SectorAngle.Value;
            app.E2SectorAngle.Value = value;
            app.disableafterProbe();
            
        end

        % Value changed function: VPlaneWidth
        function VPlaneWidthValueChanged(app, event)
            
            % Adjusts upper bounds if width is changed.
            % Not currently doing anything because Width is not editable.
            
            value = app.VPlaneWidth.Value;
            app.VPlaneUpperX.Value = value + app.VPlaneLowerX.Value;
            app.disableafterPlane();
            
        end

        % Value changed function: VPlaneHeight
        function VPlaneHeightValueChanged(app, event)
            
            % Adjusts upper bounds if width is changed.
            % Not currently doing anything because Height is not editable.

            value = app.VPlaneHeight.Value;
            app.VPlaneUpperZ.Value = value + app.VPlaneLowerZ.Value;
            app.disableafterPlane();

        end

        % Value changed function: VPlaneUpperX
        function VPlaneUpperXValueChanged(app, event)
            
            % Adjusts plane width based on inputted x values

            value = app.VPlaneUpperX.Value;
            app.VPlaneWidth.Value = abs(value - app.VPlaneLowerX.Value);
            app.disableafterPlane();

        end

        % Value changed function: VPlaneUpperZ
        function VPlaneUpperZValueChanged(app, event)
            
            % Adjusts plane height based on inputted z values

            value = app.VPlaneUpperZ.Value;
            app.VPlaneHeight.Value = abs(value - app.VPlaneLowerZ.Value);
            app.disableafterPlane();

        end

        % Button pushed function: SetPlaneValuestoDefaultButton
        function SetPlaneValuestoDefaultButtonPushed(app, event)
           
            % Sets default plane values and adjusts width/height

            % Vertical plane
            app.VPlaneLowerX.Value = 0.8;
            app.VPlaneLowerZ.Value = -5;

            app.VPlaneUpperX.Value = 15.8;
            app.VPlaneUpperZ.Value = 10;

            app.VPlaneY.Value = 0;

            app.VPlaneWidth.Value = app.VPlaneUpperX.Value - app.VPlaneLowerX.Value;
            app.VPlaneHeight.Value = app.VPlaneUpperZ.Value - app.VPlaneLowerZ.Value;

            % Horizontal plane
            app.HPlaneLowerX.Value = -5;
            app.HPlaneLowerY.Value = -5;

            app.HPlaneUpperX.Value = 5;
            app.HPlaneUpperY.Value = 5;

            app.HPlaneZ.Value = 0;

            app.HPlaneWidth.Value = app.HPlaneUpperX.Value - app.HPlaneLowerX.Value;
            app.HPlaneLength.Value = app.HPlaneUpperY.Value - app.HPlaneLowerY.Value;

            app.Conductivity.Value = 0.0001;
            
            app.disableafterPlane();

            % Switches and whatnot
            app.PlaneSwitch.Value = "Vertical";
            app.PlaneSwitchValueChanged(app)

        end

        % Value changed function: VPlaneLowerX
        function VPlaneLowerXValueChanged(app, event)
            
            % Adjusts plane width based on inputted x values

            value = app.VPlaneLowerX.Value;
            app.VPlaneWidth.Value = abs(app.VPlaneUpperX.Value - value);
            app.disableafterPlane();

        end

        % Value changed function: VPlaneLowerZ
        function VPlaneLowerZValueChanged(app, event)
            
            % Adjusts plane height based on inputted z values

            value = app.VPlaneLowerZ.Value;
            app.VPlaneHeight.Value = abs(app.VPlaneUpperZ.Value - value);
            app.disableafterPlane();

        end

        % Button pushed function: SetProbeValuestoDefaultBSCButton
        function SetProbeValuestoDefaultBSCButtonPushed(app, event)
            
            % Defuault values for BSC probe

            app.ProbeDiameter.Value = 1.3;
            app.ProbeLength.Value = 15;

            app.E4Separation.Value = 0.5;
            app.E4Height.Value = 1.5;

            app.E3Separation.Value = 0.5;
            app.E3Height.Value = 1.5;
            app.E3SectorAngle.Value = 88.15;

            app.E2Separation.Value = 0.5;
            app.E2Height.Value = 1.5;
            app.E2SectorAngle.Value = 88.15;

            app.E1Height.Value = 0.85;

            app.ProbeSwitch.Value = "Boston Scientific";
            ProbeSwitchValueChanged(app)

            app.disableafterProbe();

        end

        % Button pushed function: DisplayAllFiguresButton
        function DisplayAllFiguresButtonPushed(app, event)
            
            % Displays all figures at once 

            app.CmdOutput.Value = [app.CmdOutput.Value; "Generating and displaying all figures..."];
            app.scr()

            DisplaySurfacePotentialButtonPushed(app);
            DisplaySurfaceEFieldButtonPushed(app);
            DisplayVolumePotentialButtonPushed(app);
            DisplayVolumeEFieldButtonPushed(app);
            DisplayGradientofEFieldButtonPushed(app);

            app.CmdOutput.Value = [app.CmdOutput.Value; "Done."];
            app.scr()

        end

        % Value changed function: ProbeDiameter
        function ProbeDiameterValueChanged(app, event)
            app.disableafterProbe();
        end

        % Value changed function: ProbeLength
        function ProbeLengthValueChanged(app, event)
            app.disableafterProbe();
        end

        % Value changed function: E4Height
        function E4HeightValueChanged(app, event)
            app.disableafterProbe();
        end

        % Value changed function: E4Separation
        function E4SeparationValueChanged(app, event)
            app.disableafterProbe();
        end

        % Value changed function: E3Height
        function E3HeightValueChanged(app, event)
            app.disableafterProbe();
        end

        % Value changed function: E3Separation
        function E3SeparationValueChanged(app, event)
            app.disableafterProbe();
        end

        % Value changed function: E2Height
        function E2HeightValueChanged(app, event)
            app.disableafterProbe();
        end

        % Value changed function: E2Separation
        function E2SeparationValueChanged(app, event)
            app.disableafterProbe();
        end

        % Value changed function: E1Height
        function E1HeightValueChanged(app, event)
            app.disableafterProbe();
        end

        % Value changed function: EighthElectrodeV
        function EighthElectrodeVValueChanged(app, event)
            app.disableafterVoltage();
        end

        % Value changed function: SeventhElectrodeV
        function SeventhElectrodeVValueChanged(app, event)
            app.disableafterVoltage();
        end

        % Value changed function: SixthElectrodeV
        function SixthElectrodeVValueChanged(app, event)
            app.disableafterVoltage();
        end

        % Value changed function: FifthElectrodeV
        function FifthElectrodeVValueChanged(app, event)
            app.disableafterVoltage();
        end

        % Value changed function: FourthElectrodeV
        function FourthElectrodeVValueChanged(app, event)
            app.disableafterVoltage();
        end

        % Value changed function: ThirdElectrodeV
        function ThirdElectrodeVValueChanged(app, event)
            app.disableafterVoltage();
        end

        % Value changed function: SecondElectrodeV
        function SecondElectrodeVValueChanged(app, event)
            app.disableafterVoltage();
        end

        % Value changed function: FirstElectrodeV
        function FirstElectrodeVValueChanged(app, event)
            app.disableafterVoltage();
        end

        % Button pushed function: SaveDesignButton
        function SaveDesignButtonPushed(app, event)
            
            % Turns Modifiers data into matrix and saves it in ".txt" file

            data = app.modifierssave();
            filename = app.FilenameModifiers.Value + ".txt";
            writematrix(data,filename)

            app.CmdOutput.Value = [app.CmdOutput.Value; filename+" saved."];
            app.scr()

        end

        % Button pushed function: SelectFiletoLoadButton
        function SelectFiletoLoadButtonPushed(app, event)
            
            % Loads inputted matrix from ".txt" file into Modifiers panel

            filename = uigetfile("*.txt","Select a file to load");
            app.DBSModelGUIUIFigure.Visible = 'off';
            app.DBSModelGUIUIFigure.Visible = 'on'; % "Blink" figure so it isn't minimized
            if filename ~= 0 % Checking if user cancels process
                data = readmatrix(filename);
                app.modifiersread(data);
            end

        end

        % Button pushed function: ViewFigureButton
        function ViewFigureButtonPushed(app, event)
            
            % Saves selected figure (makes sure saved figure is not displayed

            app.ViewFigureButton.Enable = "off";

            f = figure('Position',[10,10,600,600],'Name','Resize figure to desired dimensions - Press "Save Figure" to save and continue.');
            
            if app.SelectFigureDropDown.Value == "Probe"; copyobj(app.ProbeAxes,f); end
            if app.SelectFigureDropDown.Value == "Plane"; copyobj(app.PlaneAxes,f); end
            if app.SelectFigureDropDown.Value == "Electrode Voltages"; copyobj(app.ElectrodeVoltagesAxes,f); end
            if app.SelectFigureDropDown.Value == "Convergence History"; copyobj(app.ConvergenceHistoryAxes,f); end
            if app.SelectFigureDropDown.Value == "Surface Potential"; copyobj(app.SurfacePotentialAxes,f); end
            if app.SelectFigureDropDown.Value == "Surface E-Field"; copyobj(app.SurfaceEFieldAxes,f); end
            if app.SelectFigureDropDown.Value == "Volume E-Field"; copyobj(app.VolumeEFieldAxes,f); end
            if app.SelectFigureDropDown.Value == "Grad of E-Field"; copyobj(app.GradofEFieldAxes,f); end
            if app.SelectFigureDropDown.Value == "Volume Potential"; copyobj(app.VolumePotentialAxes,f); end

            app.keycheck = 0;
            app.SaveFigureButton.Enable = "on";
            pause(0.1)

            while app.keycheck == 0
                
                if ~isvalid(f)
                    app.CmdOutput.Value = [app.CmdOutput.Value; "Figure save canceled."];
                    app.scr()
                    app.SaveFigureButton.Enable = "off";
                    app.ViewFigureButton.Enable = "on";
                    break
                end

                pause(0.1)

            end

            if app.keycheck == 1
                figname = strcat(app.FilenameFigures.Value,app.FigExtension.Value);
                saveas(f,figname)
                close(f)
                app.CmdOutput.Value = [app.CmdOutput.Value; figname+" saved."];
                app.scr()
            end
           
        end

        % Value changed function: ProbeSwitch
        function ProbeSwitchValueChanged(app, event)
            
            % Enable/disable L1 Separation depending on probe type

            value = app.ProbeSwitch.Value;
            if value == "Abbott"
    
                app.E1Separation.Enable = "on";
                d = app.checkDefaultsBSC();
                if d ==1
                    app.SetProbeValuestoDefaultAbbottButtonPushed()
                end

            else

                app.E1Separation.Enable = "off";
                d = app.checkDefaultsAbbott();
                if d == 1
                    app.SetProbeValuestoDefaultBSCButtonPushed()
                end

            end
            app.disableafterProbe()
            
        end

        % Button pushed function: SetProbeValuestoDefaultAbbottButton
        function SetProbeValuestoDefaultAbbottButtonPushed(app, event)
            
            % Default values for Abbott probe

            app.ProbeDiameter.Value = 1.29;
            app.ProbeLength.Value = 15;

            app.E4Separation.Value = 0.5;
            app.E4Height.Value = 1.5;

            app.E3Separation.Value = 0.5;
            app.E3Height.Value = 1.5;
            app.E3SectorAngle.Value = 105.7;

            app.E2Separation.Value = 0.5;
            app.E2Height.Value = 1.5;
            app.E2SectorAngle.Value = 105.7;

            app.E1Height.Value = 1.5;
            app.E1Separation.Value = 0.5;

            app.ProbeSwitch.Value = "Abbott";
            ProbeSwitchValueChanged(app)

            app.disableafterProbe();

        end

        % Value changed function: HPlaneLowerX
        function HPlaneLowerXValueChanged(app, event)
            
            % Adjusts plane width based on inputted x values

            value = app.HPlaneLowerX.Value;
            app.HPlaneWidth.Value = abs(app.HPlaneUpperX.Value - value);
            app.disableafterPlane();
            
        end

        % Value changed function: HPlaneUpperX
        function HPlaneUpperXValueChanged(app, event)
           
            % Adjusts plane width based on inputted x values

            value = app.HPlaneUpperX.Value;
            app.HPlaneWidth.Value = abs(value - app.HPlaneLowerX.Value);
            app.disableafterPlane();

        end

        % Value changed function: HPlaneLowerY
        function HPlaneLowerYValueChanged(app, event)
            
            % Adjusts plane length based on inputted x values

            value = app.HPlaneLowerY.Value;
            app.HPlaneLength.Value = abs(app.HPlaneUpperY.Value - value);
            app.disableafterPlane();
            
        end

        % Value changed function: HPlaneUpperY
        function HPlaneUpperYValueChanged(app, event)
           
            % Adjusts plane length based on inputted x values

            value = app.HPlaneUpperY.Value;
            app.HPlaneLength.Value = abs(value - app.HPlaneLowerY.Value);
            app.disableafterPlane();

        end

        % Value changed function: PlaneSwitch
        function PlaneSwitchValueChanged(app, event)
            
            % Toggling enabled values for plane tab
            
            value = app.PlaneSwitch.Value;

            if value == "Vertical"

                app.HPlaneLength.Enable = "off";
                app.HPlaneWidth.Enable = "off";
                app.HPlaneUpperY.Enable = "off";
                app.HPlaneLowerY.Enable = "off";
                app.HPlaneUpperX.Enable = "off";
                app.HPlaneLowerX.Enable = "off";
                app.HPlaneZ.Enable = "off";

                app.VPlaneHeight.Enable = "on";
                app.VPlaneWidth.Enable = "on";
                app.VPlaneUpperZ.Enable = "on";
                app.VPlaneLowerZ.Enable = "on";
                app.VPlaneUpperX.Enable = "on";
                app.VPlaneLowerX.Enable = "on";
                app.VPlaneY.Enable = "on";

                app.ColorbarCheckBox.Value = 0;
                app.ZoomtoProbeCheckBox.Value = 1;

            else

                app.VPlaneHeight.Enable = "off";
                app.VPlaneWidth.Enable = "off";
                app.VPlaneUpperZ.Enable = "off";
                app.VPlaneLowerZ.Enable = "off";
                app.VPlaneUpperX.Enable = "off";
                app.VPlaneLowerX.Enable = "off";
                app.VPlaneY.Enable = "off";

                app.HPlaneLength.Enable = "on";
                app.HPlaneWidth.Enable = "on";
                app.HPlaneUpperY.Enable = "on";
                app.HPlaneLowerY.Enable = "on";
                app.HPlaneUpperX.Enable = "on";
                app.HPlaneLowerX.Enable = "on";
                app.HPlaneZ.Enable = "on";

                app.ColorbarCheckBox.Value = 1;
                app.ZoomtoProbeCheckBox.Value = 0;

            end

            app.disableafterPlane()
            
        end

        % Value changed function: VPlaneY
        function VPlaneYValueChanged(app, event)
            app.disableafterPlane()
        end

        % Value changed function: HPlaneZ
        function HPlaneZValueChanged(app, event)
            app.disableafterPlane()
        end

        % Value changed function: Conductivity
        function ConductivityValueChanged(app, event)
            app.disableafterPlane();
        end

        % Button pushed function: SaveFigureButton
        function SaveFigureButtonPushed(app, event)
            app.keycheck = 1;
            app.SaveFigureButton.Enable = "off";
            app.ViewFigureButton.Enable = "on";
        end

        % Button pushed function: Save3DDataButton
        function Save3DDataButtonPushed(app, event)
            
            % Open Save3D window to enter bounds and step sizes
            app.Save3DApp = Save3D(app);
            app.Save3DDataButton.Enable = "off";
            app.Select3DFieldDropDown.Enable = "off";

        end

        % Close request function: DBSModelGUIUIFigure
        function DBSModelGUIUIFigureCloseRequest(app, event)
            
            % Close saving window if it is open
            delete(app.Save3DApp)
            
            delete(app)
            
        end

        % Value changed function: BaseElectrodeV
        function BaseElectrodeVValueChanged(app, event)
           app.disableafterVoltage();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DBSModelGUIUIFigure = uifigure('Visible', 'off');
            app.DBSModelGUIUIFigure.Name = 'DBS Model GUI';
            app.DBSModelGUIUIFigure.CloseRequestFcn = createCallbackFcn(app, @DBSModelGUIUIFigureCloseRequest, true);
            app.InitializeParallelProcessingButton = uibutton(app.DBSModelGUIUIFigure, 'push');
            app.InitializeParallelProcessingButton.ButtonPushedFcn = createCallbackFcn(app, @InitializeParallelProcessingButtonPushed, true);
            app.InitializeParallelProcessingButton.Text = 'Initialize Parallel Processing';
            app.StatusLampLabel = uilabel(app.DBSModelGUIUIFigure);
            app.StatusLampLabel.Text = 'Status';
            app.StatusLamp = uilamp(app.DBSModelGUIUIFigure);
            app.DisplayTabGroup = uitabgroup(app.DBSModelGUIUIFigure);
            app.ProbeTab = uitab(app.DisplayTabGroup);
            app.ProbeTab.Title = 'Probe';
            app.ProbeAxes = uiaxes(app.ProbeTab);
            xlabel(app.ProbeAxes, 'X')
            ylabel(app.ProbeAxes, 'Y')
            zlabel(app.ProbeAxes, 'Z')
            app.ProbeAxes.Visible = 'off';
            app.PlaneTab = uitab(app.DisplayTabGroup);
            app.PlaneTab.Title = 'Plane';
            app.PlaneAxes = uiaxes(app.PlaneTab);
            xlabel(app.PlaneAxes, 'X')
            ylabel(app.PlaneAxes, 'Y')
            zlabel(app.PlaneAxes, 'Z')
            app.PlaneAxes.Visible = 'off';
            app.ElectrodeVoltagesTab = uitab(app.DisplayTabGroup);
            app.ElectrodeVoltagesTab.Title = 'Electrode Voltages';
            app.ElectrodeVoltagesAxes = uiaxes(app.ElectrodeVoltagesTab);
            xlabel(app.ElectrodeVoltagesAxes, 'X')
            ylabel(app.ElectrodeVoltagesAxes, 'Y')
            zlabel(app.ElectrodeVoltagesAxes, 'Z')
            app.ElectrodeVoltagesAxes.Visible = 'off';
            app.SimulationResultsTab = uitab(app.DisplayTabGroup);
            app.SimulationResultsTab.Title = 'Simulation Results';
            app.ConvergenceHistoryAxes = uiaxes(app.SimulationResultsTab);
            xlabel(app.ConvergenceHistoryAxes, 'X')
            ylabel(app.ConvergenceHistoryAxes, 'Y')
            zlabel(app.ConvergenceHistoryAxes, 'Z')
            app.ConvergenceHistoryAxes.Visible = 'off';
            app.ResultsTable = uitable(app.SimulationResultsTab);
            app.ResultsTable.ColumnName = {'Voltage (V)'; 'Current (A)'; 'Impedance (Ohms)'};
            app.ResultsTable.RowName = {'8 / 4'; '7 / 3B'; '6 / 3C'; '5 / 3A'; '4 / 2B'; '3 / 2C'; '2 / 2A'; '1 / 1'; 'IPG'};
            app.ResultsTable.Visible = 'off';
            app.SurfacePotentialTab = uitab(app.DisplayTabGroup);
            app.SurfacePotentialTab.Title = 'Surface Potential';
            app.SurfacePotentialAxes = uiaxes(app.SurfacePotentialTab);
            xlabel(app.SurfacePotentialAxes, 'X')
            ylabel(app.SurfacePotentialAxes, 'Y')
            zlabel(app.SurfacePotentialAxes, 'Z')
            app.SurfacePotentialAxes.Visible = 'off';
            app.SurfaceEFieldTab = uitab(app.DisplayTabGroup);
            app.SurfaceEFieldTab.Title = 'Surface E-Field';
            app.SurfaceEFieldAxes = uiaxes(app.SurfaceEFieldTab);
            xlabel(app.SurfaceEFieldAxes, 'X')
            ylabel(app.SurfaceEFieldAxes, 'Y')
            zlabel(app.SurfaceEFieldAxes, 'Z')
            app.SurfaceEFieldAxes.Visible = 'off';
            app.VolumePotentialTab = uitab(app.DisplayTabGroup);
            app.VolumePotentialTab.Title = 'Volume Potential';
            app.VolumePotentialAxes = uiaxes(app.VolumePotentialTab);
            xlabel(app.VolumePotentialAxes, 'X')
            ylabel(app.VolumePotentialAxes, 'Y')
            zlabel(app.VolumePotentialAxes, 'Z')
            app.VolumePotentialAxes.Visible = 'off';
            app.VolumeEFieldTab = uitab(app.DisplayTabGroup);
            app.VolumeEFieldTab.Title = 'Volume E-Field';
            app.VolumeEFieldAxes = uiaxes(app.VolumeEFieldTab);
            xlabel(app.VolumeEFieldAxes, 'X')
            ylabel(app.VolumeEFieldAxes, 'Y')
            zlabel(app.VolumeEFieldAxes, 'Z')
            app.VolumeEFieldAxes.Visible = 'off';
            app.GradofEFieldTab = uitab(app.DisplayTabGroup);
            app.GradofEFieldTab.Title = 'Grad of E-Field';
            app.GradofEFieldAxes = uiaxes(app.GradofEFieldTab);
            title(app.GradofEFieldAxes, 'Gradient of Electric Field (V/mm^{2})')
            xlabel(app.GradofEFieldAxes, 'X')
            ylabel(app.GradofEFieldAxes, 'Y')
            zlabel(app.GradofEFieldAxes, 'Z')
            app.GradofEFieldAxes.Visible = 'off';
            app.CmdOutput = uitextarea(app.DBSModelGUIUIFigure);
            app.CmdOutput.Editable = 'off';
            app.CmdOutput.Value = {'DBS Model GUI Version 1.0'};
            app.ClearTextWindowButton = uibutton(app.DBSModelGUIUIFigure, 'push');
            app.ClearTextWindowButton.ButtonPushedFcn = createCallbackFcn(app, @ClearTextWindowButtonPushed, true);
            app.ClearTextWindowButton.Text = 'Clear Text Window';
            app.SimulationDisplayControlsPanel = uipanel(app.DBSModelGUIUIFigure);
            app.SimulationDisplayControlsPanel.Title = 'Simulation & Display Controls';
            app.GenerateProbeButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.GenerateProbeButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateProbeButtonPushed, true);
            app.GenerateProbeButton.Text = 'Generate Probe';
            app.GeneratePlaneButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.GeneratePlaneButton.ButtonPushedFcn = createCallbackFcn(app, @GeneratePlaneButtonPushed, true);
            app.GeneratePlaneButton.Enable = 'off';
            app.GeneratePlaneButton.Text = 'Generate Plane';
            app.AssignElectrodeVoltagesButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.AssignElectrodeVoltagesButton.ButtonPushedFcn = createCallbackFcn(app, @AssignElectrodeVoltagesButtonPushed, true);
            app.AssignElectrodeVoltagesButton.Enable = 'off';
            app.AssignElectrodeVoltagesButton.Text = 'Assign Electrode Voltages';
            app.RunSimulationsButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.RunSimulationsButton.ButtonPushedFcn = createCallbackFcn(app, @RunSimulationsButtonPushed, true);
            app.RunSimulationsButton.Enable = 'off';
            app.RunSimulationsButton.Text = 'Run Simulations';
            app.DisplaySurfacePotentialButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.DisplaySurfacePotentialButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySurfacePotentialButtonPushed, true);
            app.DisplaySurfacePotentialButton.Enable = 'off';
            app.DisplaySurfacePotentialButton.Text = 'Display Surface Potential';
            app.DisplaySurfaceEFieldButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.DisplaySurfaceEFieldButton.ButtonPushedFcn = createCallbackFcn(app, @DisplaySurfaceEFieldButtonPushed, true);
            app.DisplaySurfaceEFieldButton.Enable = 'off';
            app.DisplaySurfaceEFieldButton.Text = 'Display Surface E-Field';
            app.DisplayVolumeEFieldButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.DisplayVolumeEFieldButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayVolumeEFieldButtonPushed, true);
            app.DisplayVolumeEFieldButton.Enable = 'off';
            app.DisplayVolumeEFieldButton.Text = 'Display Volume E-Field';
            app.DisplayGradientofEFieldButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.DisplayGradientofEFieldButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayGradientofEFieldButtonPushed, true);
            app.DisplayGradientofEFieldButton.Enable = 'off';
            app.DisplayGradientofEFieldButton.Text = 'Display Gradient of E-Field';
            app.DisplayVolumePotentialButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.DisplayVolumePotentialButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayVolumePotentialButtonPushed, true);
            app.DisplayVolumePotentialButton.Enable = 'off';
            app.DisplayVolumePotentialButton.Text = 'Display Volume Potential';
            app.DisplayAllFiguresButton = uibutton(app.SimulationDisplayControlsPanel, 'push');
            app.DisplayAllFiguresButton.ButtonPushedFcn = createCallbackFcn(app, @DisplayAllFiguresButtonPushed, true);
            app.DisplayAllFiguresButton.Enable = 'off';
            app.DisplayAllFiguresButton.Text = 'Display All Figures';
            app.ZoomtoProbeCheckBox = uicheckbox(app.SimulationDisplayControlsPanel);
            app.ZoomtoProbeCheckBox.Enable = 'off';
            app.ZoomtoProbeCheckBox.Text = ' Zoom to Probe';
            app.ZoomtoProbeCheckBox.Value = true;
            app.ColorbarCheckBox = uicheckbox(app.SimulationDisplayControlsPanel);
            app.ColorbarCheckBox.Enable = 'off';
            app.ColorbarCheckBox.Text = 'Colorbar';
            app.SurfaceFiguresLabel = uilabel(app.SimulationDisplayControlsPanel);
            app.SurfaceFiguresLabel.Enable = 'off';
            app.SurfaceFiguresLabel.Text = 'Surface Figures';
            app.SpatialFiguresLabel = uilabel(app.SimulationDisplayControlsPanel);
            app.SpatialFiguresLabel.Enable = 'off';
            app.SpatialFiguresLabel.Text = 'Spatial Figures';
            app.ProbeDesignPanel = uipanel(app.DBSModelGUIUIFigure);
            app.ProbeDesignPanel.Title = 'Probe Design';
            app.ModifierTabGroup = uitabgroup(app.ProbeDesignPanel);
            app.LayoutTab = uitab(app.ModifierTabGroup);
            app.LayoutTab.Title = 'Layout';
            app.LayoutTabGroup = uitabgroup(app.LayoutTab);
            app.BostonScientificTab = uitab(app.LayoutTabGroup);
            app.BostonScientificTab.Title = 'Boston Scientific';
            app.BostonScientificElectrodesPanel = uipanel(app.BostonScientificTab);
            app.BostonScientificElectrodesPanel.Title = 'Boston Scientific Electrodes';
            app.LayoutBSC = uiimage(app.BostonScientificElectrodesPanel);
            app.AbbottTab = uitab(app.LayoutTabGroup);
            app.AbbottTab.Title = 'Abbott';
            app.AbbottElectrodesPanel = uipanel(app.AbbottTab);
            app.AbbottElectrodesPanel.Title = 'Abbott Electrodes';
            app.LayoutAbbott = uiimage(app.AbbottElectrodesPanel);
            app.ProbeModifierTab = uitab(app.ModifierTabGroup);
            app.ProbeModifierTab.Title = 'Specs';
            app.DiametermmEditFieldLabel = uilabel(app.ProbeModifierTab);
            app.DiametermmEditFieldLabel.Text = 'Diameter (mm)';
            app.ProbeDiameter = uieditfield(app.ProbeModifierTab, 'numeric');
            app.ProbeDiameter.Limits = [0 150];
            app.ProbeDiameter.ValueChangedFcn = createCallbackFcn(app, @ProbeDiameterValueChanged, true);
            app.ProbeDiameter.Value = 1.3;
            app.LengthmmEditFieldLabel = uilabel(app.ProbeModifierTab);
            app.LengthmmEditFieldLabel.Text = 'Length (mm)';
            app.ProbeLength = uieditfield(app.ProbeModifierTab, 'numeric');
            app.ProbeLength.Limits = [0 20.05];
            app.ProbeLength.ValueChangedFcn = createCallbackFcn(app, @ProbeLengthValueChanged, true);
            app.ProbeLength.Value = 15;
            app.ProbeLabel = uilabel(app.ProbeModifierTab);
            app.ProbeLabel.Text = 'Probe';
            app.SetProbeValuestoDefaultBSCButton = uibutton(app.ProbeModifierTab, 'push');
            app.SetProbeValuestoDefaultBSCButton.ButtonPushedFcn = createCallbackFcn(app, @SetProbeValuestoDefaultBSCButtonPushed, true);
            app.SetProbeValuestoDefaultBSCButton.Text = 'Set Probe Values to Default (BSC)';
            app.Level4L4Label = uilabel(app.ProbeModifierTab);
            app.Level4L4Label.Text = 'Level 4 (L4)';
            app.Level3L3Label = uilabel(app.ProbeModifierTab);
            app.Level3L3Label.Text = 'Level 3 (L3)';
            app.Level2L2Label = uilabel(app.ProbeModifierTab);
            app.Level2L2Label.Text = 'Level 2 (L2)';
            app.Level1L1Label = uilabel(app.ProbeModifierTab);
            app.Level1L1Label.Text = 'Level 1 (L1)';
            app.HeightmmEditFieldLabel = uilabel(app.ProbeModifierTab);
            app.HeightmmEditFieldLabel.Text = 'Height (mm)';
            app.E1Height = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E1Height.Limits = [0 Inf];
            app.E1Height.ValueChangedFcn = createCallbackFcn(app, @E1HeightValueChanged, true);
            app.E1Height.Value = 0.85;
            app.HeightmmEditField_2Label = uilabel(app.ProbeModifierTab);
            app.HeightmmEditField_2Label.Text = 'Height (mm)';
            app.E2Height = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E2Height.Limits = [0 Inf];
            app.E2Height.ValueChangedFcn = createCallbackFcn(app, @E2HeightValueChanged, true);
            app.E2Height.Value = 1.5;
            app.SeparationmmEditFieldLabel = uilabel(app.ProbeModifierTab);
            app.SeparationmmEditFieldLabel.Text = 'Separation (mm)';
            app.E2Separation = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E2Separation.Limits = [0 Inf];
            app.E2Separation.ValueChangedFcn = createCallbackFcn(app, @E2SeparationValueChanged, true);
            app.E2Separation.Value = 0.5;
            app.SectorAngledegEditFieldLabel = uilabel(app.ProbeModifierTab);
            app.SectorAngledegEditFieldLabel.Text = 'Sector Angle (deg)';
            app.E2SectorAngle = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E2SectorAngle.Limits = [0 120];
            app.E2SectorAngle.ValueChangedFcn = createCallbackFcn(app, @E2SectorAngleValueChanged, true);
            app.E2SectorAngle.Value = 88.15;
            app.HeightmmEditField_3Label = uilabel(app.ProbeModifierTab);
            app.HeightmmEditField_3Label.Text = 'Height (mm)';
            app.E3Height = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E3Height.Limits = [0 Inf];
            app.E3Height.ValueChangedFcn = createCallbackFcn(app, @E3HeightValueChanged, true);
            app.E3Height.Value = 1.5;
            app.SeparationmmEditField_2Label = uilabel(app.ProbeModifierTab);
            app.SeparationmmEditField_2Label.Text = 'Separation (mm)';
            app.E3Separation = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E3Separation.Limits = [0 Inf];
            app.E3Separation.ValueChangedFcn = createCallbackFcn(app, @E3SeparationValueChanged, true);
            app.E3Separation.Value = 0.5;
            app.SectorAngledegEditField_2Label = uilabel(app.ProbeModifierTab);
            app.SectorAngledegEditField_2Label.Text = 'Sector Angle  (deg)';
            app.E3SectorAngle = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E3SectorAngle.Limits = [0 120];
            app.E3SectorAngle.ValueChangedFcn = createCallbackFcn(app, @E3SectorAngleValueChanged, true);
            app.E3SectorAngle.Value = 88.15;
            app.HeightmmEditField_4Label = uilabel(app.ProbeModifierTab);
            app.HeightmmEditField_4Label.Text = 'Height (mm)';
            app.E4Height = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E4Height.Limits = [0 Inf];
            app.E4Height.ValueChangedFcn = createCallbackFcn(app, @E4HeightValueChanged, true);
            app.E4Height.Value = 1.5;
            app.SeparationmmEditField_3Label = uilabel(app.ProbeModifierTab);
            app.SeparationmmEditField_3Label.Text = 'Separation (mm)';
            app.E4Separation = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E4Separation.Limits = [0 Inf];
            app.E4Separation.ValueChangedFcn = createCallbackFcn(app, @E4SeparationValueChanged, true);
            app.E4Separation.Value = 0.5;
            app.ProbeSwitch = uiswitch(app.ProbeModifierTab, 'slider');
            app.ProbeSwitch.Items = {'Boston Scientific', 'Abbott'};
            app.ProbeSwitch.ValueChangedFcn = createCallbackFcn(app, @ProbeSwitchValueChanged, true);
            app.ProbeSwitch.Value = 'Boston Scientific';
            app.SetProbeValuestoDefaultAbbottButton = uibutton(app.ProbeModifierTab, 'push');
            app.SetProbeValuestoDefaultAbbottButton.ButtonPushedFcn = createCallbackFcn(app, @SetProbeValuestoDefaultAbbottButtonPushed, true);
            app.SetProbeValuestoDefaultAbbottButton.Text = 'Set Probe Values to Default (Abbott)';
            app.SeparationmmLabel = uilabel(app.ProbeModifierTab);
            app.SeparationmmLabel.Text = 'Separation (mm)';
            app.E1Separation = uieditfield(app.ProbeModifierTab, 'numeric');
            app.E1Separation.Limits = [0 Inf];
            app.E1Separation.Enable = 'off';
            app.E1Separation.Value = 0.5;
            app.PlaneModifierTab = uitab(app.ModifierTabGroup);
            app.PlaneModifierTab.Title = 'Plane';
            app.x_minmmLabel = uilabel(app.PlaneModifierTab);
            app.x_minmmLabel.Text = 'Lower x (mm)';
            app.VPlaneLowerX = uieditfield(app.PlaneModifierTab, 'numeric');
            app.VPlaneLowerX.Limits = [-75 75];
            app.VPlaneLowerX.ValueChangedFcn = createCallbackFcn(app, @VPlaneLowerXValueChanged, true);
            app.VPlaneLowerX.Value = 0.8;
            app.WidthmmEditField_2Label = uilabel(app.PlaneModifierTab);
            app.WidthmmEditField_2Label.Text = 'Width (mm)';
            app.VPlaneWidth = uieditfield(app.PlaneModifierTab, 'numeric');
            app.VPlaneWidth.Limits = [0 Inf];
            app.VPlaneWidth.ValueChangedFcn = createCallbackFcn(app, @VPlaneWidthValueChanged, true);
            app.VPlaneWidth.Editable = 'off';
            app.VPlaneWidth.Value = 15;
            app.HeightmmEditField_5Label = uilabel(app.PlaneModifierTab);
            app.HeightmmEditField_5Label.Text = 'Height (mm)';
            app.VPlaneHeight = uieditfield(app.PlaneModifierTab, 'numeric');
            app.VPlaneHeight.Limits = [0 Inf];
            app.VPlaneHeight.ValueChangedFcn = createCallbackFcn(app, @VPlaneHeightValueChanged, true);
            app.VPlaneHeight.Editable = 'off';
            app.VPlaneHeight.Value = 15;
            app.VerticalPlanexzplaneLabel = uilabel(app.PlaneModifierTab);
            app.VerticalPlanexzplaneLabel.Text = 'Vertical Plane (xz-plane)';
            app.LowerzmmEditFieldLabel = uilabel(app.PlaneModifierTab);
            app.LowerzmmEditFieldLabel.Text = 'Lower z (mm)';
            app.VPlaneLowerZ = uieditfield(app.PlaneModifierTab, 'numeric');
            app.VPlaneLowerZ.Limits = [-61.45 20.05];
            app.VPlaneLowerZ.ValueChangedFcn = createCallbackFcn(app, @VPlaneLowerZValueChanged, true);
            app.VPlaneLowerZ.Value = -5;
            app.UpperxmmEditFieldLabel = uilabel(app.PlaneModifierTab);
            app.UpperxmmEditFieldLabel.Text = 'Upper x (mm)';
            app.VPlaneUpperX = uieditfield(app.PlaneModifierTab, 'numeric');
            app.VPlaneUpperX.Limits = [-75 75];
            app.VPlaneUpperX.ValueChangedFcn = createCallbackFcn(app, @VPlaneUpperXValueChanged, true);
            app.VPlaneUpperX.Value = 15.8;
            app.UpperzmmEditFieldLabel = uilabel(app.PlaneModifierTab);
            app.UpperzmmEditFieldLabel.Text = 'Upper z (mm)';
            app.VPlaneUpperZ = uieditfield(app.PlaneModifierTab, 'numeric');
            app.VPlaneUpperZ.Limits = [-61.45 20.05];
            app.VPlaneUpperZ.ValueChangedFcn = createCallbackFcn(app, @VPlaneUpperZValueChanged, true);
            app.VPlaneUpperZ.Value = 10;
            app.SetPlaneValuestoDefaultButton = uibutton(app.PlaneModifierTab, 'push');
            app.SetPlaneValuestoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetPlaneValuestoDefaultButtonPushed, true);
            app.SetPlaneValuestoDefaultButton.Text = 'Set Plane Values to Default';
            app.HorizontalPlanexyplaneLabel = uilabel(app.PlaneModifierTab);
            app.HorizontalPlanexyplaneLabel.Text = 'Horizontal Plane (xy-plane)';
            app.x_minmmLabel_2 = uilabel(app.PlaneModifierTab);
            app.x_minmmLabel_2.Text = 'Lower x (mm)';
            app.HPlaneLowerX = uieditfield(app.PlaneModifierTab, 'numeric');
            app.HPlaneLowerX.Limits = [-75 75];
            app.HPlaneLowerX.ValueChangedFcn = createCallbackFcn(app, @HPlaneLowerXValueChanged, true);
            app.HPlaneLowerX.Enable = 'off';
            app.HPlaneLowerX.Value = -5;
            app.x_minmmLabel_3 = uilabel(app.PlaneModifierTab);
            app.x_minmmLabel_3.Text = 'Upper x (mm)';
            app.HPlaneUpperX = uieditfield(app.PlaneModifierTab, 'numeric');
            app.HPlaneUpperX.Limits = [-75 75];
            app.HPlaneUpperX.ValueChangedFcn = createCallbackFcn(app, @HPlaneUpperXValueChanged, true);
            app.HPlaneUpperX.Enable = 'off';
            app.HPlaneUpperX.Value = 5;
            app.x_minmmLabel_4 = uilabel(app.PlaneModifierTab);
            app.x_minmmLabel_4.Text = 'Lower y (mm)';
            app.HPlaneLowerY = uieditfield(app.PlaneModifierTab, 'numeric');
            app.HPlaneLowerY.Limits = [-75 75];
            app.HPlaneLowerY.ValueChangedFcn = createCallbackFcn(app, @HPlaneLowerYValueChanged, true);
            app.HPlaneLowerY.Enable = 'off';
            app.HPlaneLowerY.Value = -5;
            app.x_minmmLabel_5 = uilabel(app.PlaneModifierTab);
            app.x_minmmLabel_5.Text = 'Upper y (mm)';
            app.HPlaneUpperY = uieditfield(app.PlaneModifierTab, 'numeric');
            app.HPlaneUpperY.Limits = [-75 75];
            app.HPlaneUpperY.ValueChangedFcn = createCallbackFcn(app, @HPlaneUpperYValueChanged, true);
            app.HPlaneUpperY.Enable = 'off';
            app.HPlaneUpperY.Value = 5;
            app.WidthmmEditField_2Label_2 = uilabel(app.PlaneModifierTab);
            app.WidthmmEditField_2Label_2.Text = 'Width (mm)';
            app.HPlaneWidth = uieditfield(app.PlaneModifierTab, 'numeric');
            app.HPlaneWidth.Limits = [0 Inf];
            app.HPlaneWidth.Editable = 'off';
            app.HPlaneWidth.Enable = 'off';
            app.HPlaneWidth.Value = 10;
            app.WidthmmEditField_2Label_3 = uilabel(app.PlaneModifierTab);
            app.WidthmmEditField_2Label_3.Text = 'Length (mm)';
            app.HPlaneLength = uieditfield(app.PlaneModifierTab, 'numeric');
            app.HPlaneLength.Limits = [0 Inf];
            app.HPlaneLength.Editable = 'off';
            app.HPlaneLength.Enable = 'off';
            app.HPlaneLength.Value = 10;
            app.PlaneSwitch = uiswitch(app.PlaneModifierTab, 'slider');
            app.PlaneSwitch.Items = {'Vertical', 'Horizontal'};
            app.PlaneSwitch.ValueChangedFcn = createCallbackFcn(app, @PlaneSwitchValueChanged, true);
            app.PlaneSwitch.Value = 'Vertical';
            app.yvaluemmLabel = uilabel(app.PlaneModifierTab);
            app.yvaluemmLabel.Text = 'y-value (mm)';
            app.VPlaneY = uieditfield(app.PlaneModifierTab, 'numeric');
            app.VPlaneY.Limits = [-75 75];
            app.VPlaneY.ValueChangedFcn = createCallbackFcn(app, @VPlaneYValueChanged, true);
            app.yvaluemmLabel_2 = uilabel(app.PlaneModifierTab);
            app.yvaluemmLabel_2.Text = 'z-value (mm)';
            app.HPlaneZ = uieditfield(app.PlaneModifierTab, 'numeric');
            app.HPlaneZ.Limits = [-61.45 20.05];
            app.HPlaneZ.ValueChangedFcn = createCallbackFcn(app, @HPlaneZValueChanged, true);
            app.HPlaneZ.Enable = 'off';
            app.VolumeConductivitySmmLabel = uilabel(app.PlaneModifierTab);
            app.VolumeConductivitySmmLabel.Text = 'Volume Conductivity (S/mm)';
            app.Conductivity = uieditfield(app.PlaneModifierTab, 'numeric');
            app.Conductivity.Limits = [0 Inf];
            app.Conductivity.ValueChangedFcn = createCallbackFcn(app, @ConductivityValueChanged, true);
            app.Conductivity.Value = 0.0001;
            app.VoltagesModifierTab = uitab(app.ModifierTabGroup);
            app.VoltagesModifierTab.Title = 'Voltages';
            app.SetVoltageValuestoDefaultButton = uibutton(app.VoltagesModifierTab, 'push');
            app.SetVoltageValuestoDefaultButton.ButtonPushedFcn = createCallbackFcn(app, @SetVoltageValuestoDefaultButtonPushed, true);
            app.SetVoltageValuestoDefaultButton.Text = 'Set Voltage Values to Default';
            app.Electrode84VLabel = uilabel(app.VoltagesModifierTab);
            app.Electrode84VLabel.Text = 'Electrode 8 / 4 (V)';
            app.EighthElectrodeV = uieditfield(app.VoltagesModifierTab, 'numeric');
            app.EighthElectrodeV.AllowEmpty = 'on';
            app.EighthElectrodeV.ValueChangedFcn = createCallbackFcn(app, @EighthElectrodeVValueChanged, true);
            app.EighthElectrodeV.Value = [];
            app.Electrode73BLabel = uilabel(app.VoltagesModifierTab);
            app.Electrode73BLabel.Text = 'Electrode 7 / 3B (V)';
            app.SeventhElectrodeV = uieditfield(app.VoltagesModifierTab, 'numeric');
            app.SeventhElectrodeV.AllowEmpty = 'on';
            app.SeventhElectrodeV.ValueChangedFcn = createCallbackFcn(app, @SeventhElectrodeVValueChanged, true);
            app.SeventhElectrodeV.Value = [];
            app.Electrode63CVLabel = uilabel(app.VoltagesModifierTab);
            app.Electrode63CVLabel.Text = 'Electrode 6 / 3C (V)';
            app.SixthElectrodeV = uieditfield(app.VoltagesModifierTab, 'numeric');
            app.SixthElectrodeV.AllowEmpty = 'on';
            app.SixthElectrodeV.ValueChangedFcn = createCallbackFcn(app, @SixthElectrodeVValueChanged, true);
            app.SixthElectrodeV.Value = [];
            app.Electrode53AVLabel = uilabel(app.VoltagesModifierTab);
            app.Electrode53AVLabel.Text = 'Electrode 5 / 3A (V)';
            app.FifthElectrodeV = uieditfield(app.VoltagesModifierTab, 'numeric');
            app.FifthElectrodeV.AllowEmpty = 'on';
            app.FifthElectrodeV.ValueChangedFcn = createCallbackFcn(app, @FifthElectrodeVValueChanged, true);
            app.FifthElectrodeV.Value = [];
            app.Electrode42BVLabel = uilabel(app.VoltagesModifierTab);
            app.Electrode42BVLabel.Text = 'Electrode 4 / 2B (V)';
            app.FourthElectrodeV = uieditfield(app.VoltagesModifierTab, 'numeric');
            app.FourthElectrodeV.AllowEmpty = 'on';
            app.FourthElectrodeV.ValueChangedFcn = createCallbackFcn(app, @FourthElectrodeVValueChanged, true);
            app.FourthElectrodeV.Value = [];
            app.Electrode32CVLabel = uilabel(app.VoltagesModifierTab);
            app.Electrode32CVLabel.Text = 'Electrode 3 / 2C (V)';
            app.ThirdElectrodeV = uieditfield(app.VoltagesModifierTab, 'numeric');
            app.ThirdElectrodeV.AllowEmpty = 'on';
            app.ThirdElectrodeV.ValueChangedFcn = createCallbackFcn(app, @ThirdElectrodeVValueChanged, true);
            app.ThirdElectrodeV.Value = [];
            app.Electrode22AVLabel = uilabel(app.VoltagesModifierTab);
            app.Electrode22AVLabel.Text = 'Electrode 2 / 2A (V)';
            app.SecondElectrodeV = uieditfield(app.VoltagesModifierTab, 'numeric');
            app.SecondElectrodeV.AllowEmpty = 'on';
            app.SecondElectrodeV.ValueChangedFcn = createCallbackFcn(app, @SecondElectrodeVValueChanged, true);
            app.SecondElectrodeV.Value = [];
            app.Electrode11VLabel = uilabel(app.VoltagesModifierTab);
            app.Electrode11VLabel.Text = 'Electrode 1 / 1 (V)';
            app.FirstElectrodeV = uieditfield(app.VoltagesModifierTab, 'numeric');
            app.FirstElectrodeV.ValueChangedFcn = createCallbackFcn(app, @FirstElectrodeVValueChanged, true);
            app.FirstElectrodeV.Value = -1;
            app.Level4L4Label_2 = uilabel(app.VoltagesModifierTab);
            app.Level4L4Label_2.Text = 'Level 4 (L4)';
            app.Level3L3Label_2 = uilabel(app.VoltagesModifierTab);
            app.Level3L3Label_2.Text = 'Level 3 (L3)';
            app.Level2L2Label_2 = uilabel(app.VoltagesModifierTab);
            app.Level2L2Label_2.Text = 'Level 2 (L2)';
            app.Level1L1Label_2 = uilabel(app.VoltagesModifierTab);
            app.Level1L1Label_2.Text = 'Level 1 (L1)';
            app.LeaveeditfieldblankforunknownfloatingelectrodesLabel = uilabel(app.VoltagesModifierTab);
            app.LeaveeditfieldblankforunknownfloatingelectrodesLabel.Text = 'Leave edit field blank for unknown/floating electrodes.';
            app.Electrode11VLabel_2 = uilabel(app.VoltagesModifierTab);
            app.Electrode11VLabel_2.Text = 'IPG (Base electrode) (V)';
            app.BaseElectrodeV = uieditfield(app.VoltagesModifierTab, 'numeric');
            app.BaseElectrodeV.AllowEmpty = 'on';
            app.BaseElectrodeV.ValueChangedFcn = createCallbackFcn(app, @BaseElectrodeVValueChanged, true);
            app.IPGLabel = uilabel(app.VoltagesModifierTab);
            app.IPGLabel.Text = 'IPG';
            app.LoadSaveDataPanel = uipanel(app.DBSModelGUIUIFigure);
            app.LoadSaveDataPanel.Title = 'Load & Save Data';
            app.TabGroup = uitabgroup(app.LoadSaveDataPanel);
            app.LoadDesignTab = uitab(app.TabGroup);
            app.LoadDesignTab.Title = 'Load Design';
            app.SelectFiletoLoadButton = uibutton(app.LoadDesignTab, 'push');
            app.SelectFiletoLoadButton.ButtonPushedFcn = createCallbackFcn(app, @SelectFiletoLoadButtonPushed, true);
            app.SelectFiletoLoadButton.Text = 'Select File to Load';
            app.SelectafilewithtxtextensionthatwascreatedusingthisappLabel = uilabel(app.LoadDesignTab);
            app.SelectafilewithtxtextensionthatwascreatedusingthisappLabel.Text = 'Select a file with ".txt" extension that was created using this app.';
            app.SaveDesignTab = uitab(app.TabGroup);
            app.SaveDesignTab.Title = 'Save Design';
            app.FilenameLabel = uilabel(app.SaveDesignTab);
            app.FilenameLabel.Text = 'File name:';
            app.FilenameModifiers = uieditfield(app.SaveDesignTab, 'text');
            app.FilenameModifiers.Value = 'specs';
            app.txtLabel = uilabel(app.SaveDesignTab);
            app.txtLabel.Text = '.txt';
            app.SaveDesignButton = uibutton(app.SaveDesignTab, 'push');
            app.SaveDesignButton.ButtonPushedFcn = createCallbackFcn(app, @SaveDesignButtonPushed, true);
            app.SaveDesignButton.Text = 'Save Design';
            app.SaveFiguresTab = uitab(app.TabGroup);
            app.SaveFiguresTab.Title = 'Save Figures';
            app.SelectFigureLabel = uilabel(app.SaveFiguresTab);
            app.SelectFigureLabel.Text = 'Select Figure:';
            app.SelectFigureDropDown = uidropdown(app.SaveFiguresTab);
            app.SelectFigureDropDown.Items = {'Probe', 'Plane', 'Electrode Voltages', 'Convergence History', 'Surface Potential', 'Surface E-Field', 'Volume Potential', 'Volume E-Field', 'Grad of E-Field'};
            app.SelectFigureDropDown.Value = 'Volume E-Field';
            app.FilenameEditField_2Label = uilabel(app.SaveFiguresTab);
            app.FilenameEditField_2Label.Text = 'File name:';
            app.FilenameFigures = uieditfield(app.SaveFiguresTab, 'text');
            app.FilenameFigures.Value = 'figure';
            app.ViewFigureButton = uibutton(app.SaveFiguresTab, 'push');
            app.ViewFigureButton.ButtonPushedFcn = createCallbackFcn(app, @ViewFigureButtonPushed, true);
            app.ViewFigureButton.Text = 'View Figure';
            app.FigExtension = uidropdown(app.SaveFiguresTab);
            app.FigExtension.Items = {'.fig', '.jpg', '.png', '.pdf'};
            app.FigExtension.Value = '.fig';
            app.SaveFigureButton = uibutton(app.SaveFiguresTab, 'push');
            app.SaveFigureButton.ButtonPushedFcn = createCallbackFcn(app, @SaveFigureButtonPushed, true);
            app.SaveFigureButton.Enable = 'off';
            app.SaveFigureButton.Text = 'Save Figure';
            app.Save3DDataTab = uitab(app.TabGroup);
            app.Save3DDataTab.Title = 'Save 3D Data';
            app.Save3DDataButton = uibutton(app.Save3DDataTab, 'push');
            app.Save3DDataButton.ButtonPushedFcn = createCallbackFcn(app, @Save3DDataButtonPushed, true);
            app.Save3DDataButton.Enable = 'off';
            app.Save3DDataButton.Text = 'Save 3D Data';
            app.Select3DFieldDropDownLabel = uilabel(app.Save3DDataTab);
            app.Select3DFieldDropDownLabel.Text = 'Select 3D Field';
            app.Select3DFieldDropDown = uidropdown(app.Save3DDataTab);
            app.Select3DFieldDropDown.Items = {'Volume Potential', 'Volume E-Field'};
            app.Select3DFieldDropDown.Value = 'Volume Potential';
            % Show the figure after all components are created
            app.DBSModelGUIUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DBSModelGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DBSModelGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DBSModelGUIUIFigure)
        end
    end
end