classdef Save3D < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        Save3DDataUIFigure  matlab.ui.Figure
        SavingmaytakeawhileiftheenteredgridisverylargeandpreciseLabel  matlab.ui.control.Label
        SavebuttondisablesiftablehasinvalidentiresLabel  matlab.ui.control.Label
        SaveButton          matlab.ui.control.Button
        ExportTable         matlab.ui.control.Table
    end

    
    properties (Access = private)
        MainApp
    end
    
    methods (Access = private)
        
        function good = checktable(app)

            d = app.ExportTable.Data;

            % Checking for bad inputs
            if d(1,2) <= d(1,1); good = 0;
            elseif d(2,2) <= d(2,1); good = 0;
            elseif d(3,2) <= d(3,1); good = 0;
            elseif d(1,3) <= 0 || d(2,3) <= 0 || d(3,3) <= 0; good = 0;
            elseif anynan(d); good = 0;
            elseif d(1,3) >= d(1,2)-d(1,1); good = 0;
            elseif d(2,3) >= d(2,2)-d(2,1); good = 0;
            elseif d(3,3) >= d(3,2)-d(3,1); good = 0;
            else; good = 1;
            end
            
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp)
            
            % Store main app in property
            app.MainApp = mainapp;
            
            % Initialize table
            app.ExportTable.Data = [-5,5,0.5;-5,5,0.5;-5,5,0.5];

        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            
            % First check for valid table entries
            good = checktable(app); 
            if good == 1

                % Call public save3D function to save data
                if isvalid(app.MainApp)
                    app.SaveButton.Enable = "off";
                    save3D(app.MainApp,app.ExportTable.Data);
                end
                app.MainApp.Save3DDataButton.Enable = "on";
                app.MainApp.Select3DFieldDropDown.Enable = "on";
                delete(app); % Close Save3D window

            end

        end

        % Cell edit callback: ExportTable
        function ExportTableCellEdit(app, event)
            
            % Check for valid table entries, update save button accordingly
            good = checktable(app);
            if good == 1; app.SaveButton.Enable = "on"; 
            else; app.SaveButton.Enable = "off"; end

        end

        % Close request function: Save3DDataUIFigure
        function Save3DDataUIFigureCloseRequest(app, event)
            
            % Re-enable Save button in main app
            if isvalid(app.MainApp)
                app.MainApp.Save3DDataButton.Enable = "on";
                app.MainApp.Select3DFieldDropDown.Enable = "on";
            end
            
            delete(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.Save3DDataUIFigure = uifigure('Visible', 'off');
            app.Save3DDataUIFigure.Name = 'Save 3D Data';
            app.Save3DDataUIFigure.CloseRequestFcn = createCallbackFcn(app, @Save3DDataUIFigureCloseRequest, true);
            app.ExportTable = uitable(app.Save3DDataUIFigure);
            app.ExportTable.ColumnName = {'Min (mm)'; 'Max (mm)'; 'Step size (mm)'};
            app.ExportTable.RowName = {'x'; 'y'; 'z'};
            app.ExportTable.ColumnEditable = true;
            app.ExportTable.CellEditCallback = createCallbackFcn(app, @ExportTableCellEdit, true);
            app.ExportTable.Multiselect = 'off';
            app.SaveButton = uibutton(app.Save3DDataUIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.SavebuttondisablesiftablehasinvalidentiresLabel = uilabel(app.Save3DDataUIFigure);
            app.SavebuttondisablesiftablehasinvalidentiresLabel.Text = 'Save button disables if table has invalid entires.';
            app.SavingmaytakeawhileiftheenteredgridisverylargeandpreciseLabel = uilabel(app.Save3DDataUIFigure);
            app.SavingmaytakeawhileiftheenteredgridisverylargeandpreciseLabel.Text = 'Saving may take a while if the entered grid is very large and precise.';
            % Show the figure after all components are created
            app.Save3DDataUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Save3D(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.Save3DDataUIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.Save3DDataUIFigure)
        end
    end
end